classdef BMC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SymbolicDisplayButton           matlab.ui.control.Button
        MauriceFiloDBSSEETHzLabel       matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        NetworkDescriptionTab           matlab.ui.container.Tab
        ControllerPanel                 matlab.ui.container.Panel
        NamesListController             matlab.ui.control.ListBox
        ListofControllerSpeciesLabel    matlab.ui.control.Label
        ControllerReactionsTable        matlab.ui.control.Table
        PlantPanel_2                    matlab.ui.container.Panel
        OutputSpeciesDropDown           matlab.ui.control.DropDown
        OutputSpeciesDropDownLabel      matlab.ui.control.Label
        NamesListPlant                  matlab.ui.control.ListBox
        ListofPlantSpeciesLabel         matlab.ui.control.Label
        PlantReactionsTable             matlab.ui.control.Table
        NetworkDiagramTab               matlab.ui.container.Tab
        Image                           matlab.ui.control.Image
        StatusProcessingLabel           matlab.ui.control.Label
        ComputationTime                 matlab.ui.control.Label
        LastComputationTimeLabel        matlab.ui.control.Label
        DisturbanceTrackingPanel        matlab.ui.container.Panel
        DropDown_Param_3                matlab.ui.control.DropDown
        DropDown_Param_2                matlab.ui.control.DropDown
        DropDown_Param_1                matlab.ui.control.DropDown
        CheckBox_2                      matlab.ui.control.CheckBox
        CheckBox_1                      matlab.ui.control.CheckBox
        Lamp                            matlab.ui.control.Lamp
        t2DisturbanceFactor_Param_3     matlab.ui.control.NumericEditField
        xLabel_6                        matlab.ui.control.Label
        t2DisturbanceFactor_Param_2     matlab.ui.control.NumericEditField
        xLabel_5                        matlab.ui.control.Label
        t2DisturbanceFactor_Param_1     matlab.ui.control.NumericEditField
        xLabel_4                        matlab.ui.control.Label
        t1DisturbanceFactor_Param_3     matlab.ui.control.NumericEditField
        xLabel_3                        matlab.ui.control.Label
        t1DisturbanceFactor_Param_2     matlab.ui.control.NumericEditField
        xLabel_2                        matlab.ui.control.Label
        t1DisturbanceFactor_Param_1     matlab.ui.control.NumericEditField
        xLabel                          matlab.ui.control.Label
        t2Disturbance                   matlab.ui.control.NumericEditField
        t2EditFieldLabel                matlab.ui.control.Label
        t1Disturbance                   matlab.ui.control.NumericEditField
        t1EditFieldLabel                matlab.ui.control.Label
        SwitchDisturbance               matlab.ui.control.ToggleSwitch
        TuningCurvesPanel               matlab.ui.container.Panel
        SetPointEditField               matlab.ui.control.EditField
        SetPointEditFieldLabel          matlab.ui.control.Label
        TuningCurvesAxes                matlab.ui.control.UIAxes
        StochasticSimulationPanel       matlab.ui.container.Panel
        ParallelComputingToolboxCheckBox  matlab.ui.control.CheckBox
        Statistics                      matlab.ui.control.Button
        withStatisticsCheckBox          matlab.ui.control.CheckBox
        StochasticPlots                 matlab.ui.control.Button
        ExportStochasticButton          matlab.ui.control.Button
        StochasticSimulationSettingsPanel  matlab.ui.container.Panel
        MaximumNumberofEvents           matlab.ui.control.NumericEditField
        MaximumNumberofEventsLabel      matlab.ui.control.Label
        StochasticSimulationTime        matlab.ui.control.NumericEditField
        SimulationTimeEditFieldLabel_2  matlab.ui.control.Label
        NumberofTrajectoriesSlider      matlab.ui.control.Slider
        NumberofTrajectoriesSliderLabel  matlab.ui.control.Label
        TrajectoriesHigh                matlab.ui.control.NumericEditField
        TrajectoriesLow                 matlab.ui.control.NumericEditField
        StochasticTimeSamplesSlider     matlab.ui.control.Slider
        TimeSamplesSlider_2Label        matlab.ui.control.Label
        StochasticTimeSamplesSliderHigh  matlab.ui.control.NumericEditField
        StochasticTimeSamplesSliderLow  matlab.ui.control.NumericEditField
        RunStochasticButton             matlab.ui.control.Button
        StochasticResponseAxes          matlab.ui.control.UIAxes
        DeterministicSimulationPanel    matlab.ui.container.Panel
        DeterministicPlots              matlab.ui.control.Button
        ExportDeterministicButton       matlab.ui.control.Button
        DeterministicSimulationSettingsPanel  matlab.ui.container.Panel
        DeterministicSimulationTime     matlab.ui.control.NumericEditField
        SimulationTimeEditFieldLabel    matlab.ui.control.Label
        SolverDropDown                  matlab.ui.control.DropDown
        SolverDropDownLabel             matlab.ui.control.Label
        TimeSamplesSlider               matlab.ui.control.Slider
        TimeSamplesSliderLabel          matlab.ui.control.Label
        TimeSamplesPanelHigh            matlab.ui.control.NumericEditField
        TimeSamplesPanelLow             matlab.ui.control.NumericEditField
        RunDeterministicButton          matlab.ui.control.Button
        DeterministicResponseAxes       matlab.ui.control.UIAxes
        PlantPanel                      matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        Panel1Tab                       matlab.ui.container.Tab
        PlantKnobPanel_4                matlab.ui.container.Panel
        PlantKnobHigh_4                 matlab.ui.control.NumericEditField
        PlantKnobLow_4                  matlab.ui.control.NumericEditField
        PlantKnobLabel_4                matlab.ui.control.Label
        PlantKnob_4                     matlab.ui.control.Knob
        PlantKnobPanel_3                matlab.ui.container.Panel
        PlantKnobHigh_3                 matlab.ui.control.NumericEditField
        PlantKnobLow_3                  matlab.ui.control.NumericEditField
        PlantKnobLabel_3                matlab.ui.control.Label
        PlantKnob_3                     matlab.ui.control.Knob
        PlantKnobPanel_2                matlab.ui.container.Panel
        PlantKnobHigh_2                 matlab.ui.control.NumericEditField
        PlantKnobLow_2                  matlab.ui.control.NumericEditField
        PlantKnobLabel_2                matlab.ui.control.Label
        PlantKnob_2                     matlab.ui.control.Knob
        PlantKnobPanel_1                matlab.ui.container.Panel
        PlantKnobHigh_1                 matlab.ui.control.NumericEditField
        PlantKnobLow_1                  matlab.ui.control.NumericEditField
        PlantKnobLabel_1                matlab.ui.control.Label
        PlantKnob_1                     matlab.ui.control.Knob
        Panel2Tab                       matlab.ui.container.Tab
        PlantKnobPanel_8                matlab.ui.container.Panel
        PlantKnobHigh_8                 matlab.ui.control.NumericEditField
        PlantKnobLow_8                  matlab.ui.control.NumericEditField
        PlantKnobLabel_8                matlab.ui.control.Label
        PlantKnob_8                     matlab.ui.control.Knob
        PlantKnobPanel_7                matlab.ui.container.Panel
        PlantKnobHigh_7                 matlab.ui.control.NumericEditField
        PlantKnobLow_7                  matlab.ui.control.NumericEditField
        PlantKnobLabel_7                matlab.ui.control.Label
        PlantKnob_7                     matlab.ui.control.Knob
        PlantKnobPanel_6                matlab.ui.container.Panel
        PlantKnobHigh_6                 matlab.ui.control.NumericEditField
        PlantKnobLow_6                  matlab.ui.control.NumericEditField
        PlantKnobLabel_6                matlab.ui.control.Label
        PlantKnob_6                     matlab.ui.control.Knob
        PlantKnobPanel_5                matlab.ui.container.Panel
        PlantKnobHigh_5                 matlab.ui.control.NumericEditField
        PlantKnobLow_5                  matlab.ui.control.NumericEditField
        PlantKnobLabel_5                matlab.ui.control.Label
        PlantKnob_5                     matlab.ui.control.Knob
        Panel3Tab_2                     matlab.ui.container.Tab
        PlantKnobPanel_12               matlab.ui.container.Panel
        PlantKnobHigh_12                matlab.ui.control.NumericEditField
        PlantKnobLow_12                 matlab.ui.control.NumericEditField
        PlantKnobLabel_12               matlab.ui.control.Label
        PlantKnob_12                    matlab.ui.control.Knob
        PlantKnobPanel_11               matlab.ui.container.Panel
        PlantKnobHigh_11                matlab.ui.control.NumericEditField
        PlantKnobLow_11                 matlab.ui.control.NumericEditField
        PlantKnobLabel_11               matlab.ui.control.Label
        PlantKnob_11                    matlab.ui.control.Knob
        PlantKnobPanel_10               matlab.ui.container.Panel
        PlantKnobHigh_10                matlab.ui.control.NumericEditField
        PlantKnobLow_10                 matlab.ui.control.NumericEditField
        PlantKnobLabel_10               matlab.ui.control.Label
        PlantKnob_10                    matlab.ui.control.Knob
        PlantKnobPanel_9                matlab.ui.container.Panel
        PlantKnobHigh_9                 matlab.ui.control.NumericEditField
        PlantKnobLow_9                  matlab.ui.control.NumericEditField
        PlantKnobLabel_9                matlab.ui.control.Label
        PlantKnob_9                     matlab.ui.control.Knob
        Panel4Tab                       matlab.ui.container.Tab
        PlantKnobPanel_16               matlab.ui.container.Panel
        PlantKnobHigh_16                matlab.ui.control.NumericEditField
        PlantKnobLow_16                 matlab.ui.control.NumericEditField
        PlantKnobLabel_16               matlab.ui.control.Label
        PlantKnob_16                    matlab.ui.control.Knob
        PlantKnobPanel_15               matlab.ui.container.Panel
        PlantKnobHigh_15                matlab.ui.control.NumericEditField
        PlantKnobLow_15                 matlab.ui.control.NumericEditField
        PlantKnobLabel_15               matlab.ui.control.Label
        PlantKnob_15                    matlab.ui.control.Knob
        PlantKnobPanel_14               matlab.ui.container.Panel
        PlantKnobHigh_14                matlab.ui.control.NumericEditField
        PlantKnobLow_14                 matlab.ui.control.NumericEditField
        PlantKnobLabel_14               matlab.ui.control.Label
        PlantKnob_14                    matlab.ui.control.Knob
        PlantKnobPanel_13               matlab.ui.container.Panel
        PlantKnobHigh_13                matlab.ui.control.NumericEditField
        PlantKnobLow_13                 matlab.ui.control.NumericEditField
        PlantKnobLabel_13               matlab.ui.control.Label
        PlantKnob_13                    matlab.ui.control.Knob
        ControlPanel                    matlab.ui.container.Panel
        TabGroup3                       matlab.ui.container.TabGroup
        Panel1Tab_2                     matlab.ui.container.Tab
        ControlKnobPanel_9              matlab.ui.container.Panel
        ControlKnobHigh_9               matlab.ui.control.NumericEditField
        ControlKnobLow_9                matlab.ui.control.NumericEditField
        ControlKnobLabel_9              matlab.ui.control.Label
        ControlKnob_9                   matlab.ui.control.Knob
        ControlKnobPanel_6              matlab.ui.container.Panel
        ControlKnobHigh_6               matlab.ui.control.NumericEditField
        ControlKnobLow_6                matlab.ui.control.NumericEditField
        ControlKnobLabel_6              matlab.ui.control.Label
        ControlKnob_6                   matlab.ui.control.Knob
        ControlKnobPanel_5              matlab.ui.container.Panel
        ControlKnobHigh_5               matlab.ui.control.NumericEditField
        ControlKnobLow_5                matlab.ui.control.NumericEditField
        ControlKnobLabel_5              matlab.ui.control.Label
        ControlKnob_5                   matlab.ui.control.Knob
        ControlKnobPanel_10             matlab.ui.container.Panel
        ControlKnobHigh_10              matlab.ui.control.NumericEditField
        ControlKnobLow_10               matlab.ui.control.NumericEditField
        ControlKnobLabel_10             matlab.ui.control.Label
        ControlKnob_10                  matlab.ui.control.Knob
        ControlKnobPanel_8              matlab.ui.container.Panel
        ControlKnobHigh_8               matlab.ui.control.NumericEditField
        ControlKnobLow_8                matlab.ui.control.NumericEditField
        ControlKnobLabel_8              matlab.ui.control.Label
        ControlKnob_8                   matlab.ui.control.Knob
        ControlKnobPanel_7              matlab.ui.container.Panel
        ControlKnobHigh_7               matlab.ui.control.NumericEditField
        ControlKnobLow_7                matlab.ui.control.NumericEditField
        ControlKnobLabel_7              matlab.ui.control.Label
        ControlKnob_7                   matlab.ui.control.Knob
        ControlKnobPanel_4              matlab.ui.container.Panel
        ControlKnobHigh_4               matlab.ui.control.NumericEditField
        ControlKnobLow_4                matlab.ui.control.NumericEditField
        ControlKnobLabel_4              matlab.ui.control.Label
        ControlKnob_4                   matlab.ui.control.Knob
        ControlKnobPanel_3              matlab.ui.container.Panel
        ControlKnobHigh_3               matlab.ui.control.NumericEditField
        ControlKnobLow_3                matlab.ui.control.NumericEditField
        ControlKnobLabel_3              matlab.ui.control.Label
        ControlKnob_3                   matlab.ui.control.Knob
        ControlKnobPanel_2              matlab.ui.container.Panel
        ControlKnobHigh_2               matlab.ui.control.NumericEditField
        ControlKnobLow_2                matlab.ui.control.NumericEditField
        ControlKnobLabel_2              matlab.ui.control.Label
        ControlKnob_2                   matlab.ui.control.Knob
        ControlKnobPanel_1              matlab.ui.container.Panel
        ControlKnobHigh_1               matlab.ui.control.NumericEditField
        ControlKnobLow_1                matlab.ui.control.NumericEditField
        ControlKnobLabel_1              matlab.ui.control.Label
        ControlKnob_1                   matlab.ui.control.Knob
        Panel2Tab_2                     matlab.ui.container.Tab
        ControlKnobPanel_20             matlab.ui.container.Panel
        ControlKnobHigh_20              matlab.ui.control.NumericEditField
        ControlKnobLow_20               matlab.ui.control.NumericEditField
        ControlKnobLabel_20             matlab.ui.control.Label
        ControlKnob_20                  matlab.ui.control.Knob
        ControlKnobPanel_19             matlab.ui.container.Panel
        ControlKnobHigh_19              matlab.ui.control.NumericEditField
        ControlKnobLow_19               matlab.ui.control.NumericEditField
        ControlKnobLabel_19             matlab.ui.control.Label
        ControlKnob_19                  matlab.ui.control.Knob
        ControlKnobPanel_18             matlab.ui.container.Panel
        ControlKnobHigh_18              matlab.ui.control.NumericEditField
        ControlKnobLow_18               matlab.ui.control.NumericEditField
        ControlKnobLabel_18             matlab.ui.control.Label
        ControlKnob_18                  matlab.ui.control.Knob
        ControlKnobPanel_17             matlab.ui.container.Panel
        ControlKnobHigh_17              matlab.ui.control.NumericEditField
        ControlKnobLow_17               matlab.ui.control.NumericEditField
        ControlKnobLabel_17             matlab.ui.control.Label
        ControlKnob_17                  matlab.ui.control.Knob
        ControlKnobPanel_16             matlab.ui.container.Panel
        ControlKnobHigh_16              matlab.ui.control.NumericEditField
        ControlKnobLow_16               matlab.ui.control.NumericEditField
        ControlKnobLabel_16             matlab.ui.control.Label
        ControlKnob_16                  matlab.ui.control.Knob
        ControlKnobPanel_15             matlab.ui.container.Panel
        ControlKnobHigh_15              matlab.ui.control.NumericEditField
        ControlKnobLow_15               matlab.ui.control.NumericEditField
        ControlKnobLabel_15             matlab.ui.control.Label
        ControlKnob_15                  matlab.ui.control.Knob
        ControlKnobPanel_14             matlab.ui.container.Panel
        ControlKnobHigh_14              matlab.ui.control.NumericEditField
        ControlKnobLow_14               matlab.ui.control.NumericEditField
        ControlKnobLabel_14             matlab.ui.control.Label
        ControlKnob_14                  matlab.ui.control.Knob
        ControlKnobPanel_13             matlab.ui.container.Panel
        ControlKnobHigh_13              matlab.ui.control.NumericEditField
        ControlKnobLow_13               matlab.ui.control.NumericEditField
        ControlKnobLabel_13             matlab.ui.control.Label
        ControlKnob_13                  matlab.ui.control.Knob
        ControlKnobPanel_12             matlab.ui.container.Panel
        ControlKnobHigh_12              matlab.ui.control.NumericEditField
        ControlKnobLow_12               matlab.ui.control.NumericEditField
        ControlKnobLabel_12             matlab.ui.control.Label
        ControlKnob_12                  matlab.ui.control.Knob
        ControlKnobPanel_11             matlab.ui.container.Panel
        ControlKnobHigh_11              matlab.ui.control.NumericEditField
        ControlKnobLow_11               matlab.ui.control.NumericEditField
        ControlKnobLabel_11             matlab.ui.control.Label
        ControlKnob_11                  matlab.ui.control.Knob
        Panel3Tab                       matlab.ui.container.Tab
        ControlKnobPanel_30             matlab.ui.container.Panel
        ControlKnobHigh_30              matlab.ui.control.NumericEditField
        ControlKnobLow_30               matlab.ui.control.NumericEditField
        ControlKnobLabel_30             matlab.ui.control.Label
        ControlKnob_30                  matlab.ui.control.Knob
        ControlKnobPanel_29             matlab.ui.container.Panel
        ControlKnobHigh_29              matlab.ui.control.NumericEditField
        ControlKnobLow_29               matlab.ui.control.NumericEditField
        ControlKnobLabel_29             matlab.ui.control.Label
        ControlKnob_29                  matlab.ui.control.Knob
        ControlKnobPanel_28             matlab.ui.container.Panel
        ControlKnobHigh_28              matlab.ui.control.NumericEditField
        ControlKnobLow_28               matlab.ui.control.NumericEditField
        ControlKnobLabel_28             matlab.ui.control.Label
        ControlKnob_28                  matlab.ui.control.Knob
        ControlKnobPanel_27             matlab.ui.container.Panel
        ControlKnobHigh_27              matlab.ui.control.NumericEditField
        ControlKnobLow_27               matlab.ui.control.NumericEditField
        ControlKnobLabel_27             matlab.ui.control.Label
        ControlKnob_27                  matlab.ui.control.Knob
        ControlKnobPanel_26             matlab.ui.container.Panel
        ControlKnobHigh_26              matlab.ui.control.NumericEditField
        ControlKnobLow_26               matlab.ui.control.NumericEditField
        ControlKnobLabel_26             matlab.ui.control.Label
        ControlKnob_26                  matlab.ui.control.Knob
        ControlKnobPanel_25             matlab.ui.container.Panel
        ControlKnobHigh_25              matlab.ui.control.NumericEditField
        ControlKnobLow_25               matlab.ui.control.NumericEditField
        ControlKnobLabel_25             matlab.ui.control.Label
        ControlKnob_25                  matlab.ui.control.Knob
        ControlKnobPanel_24             matlab.ui.container.Panel
        ControlKnobHigh_24              matlab.ui.control.NumericEditField
        ControlKnobLow_24               matlab.ui.control.NumericEditField
        ControlKnobLabel_24             matlab.ui.control.Label
        ControlKnob_24                  matlab.ui.control.Knob
        ControlKnobPanel_23             matlab.ui.container.Panel
        ControlKnobHigh_23              matlab.ui.control.NumericEditField
        ControlKnobLow_23               matlab.ui.control.NumericEditField
        ControlKnobLabel_23             matlab.ui.control.Label
        ControlKnob_23                  matlab.ui.control.Knob
        ControlKnobPanel_22             matlab.ui.container.Panel
        ControlKnobHigh_22              matlab.ui.control.NumericEditField
        ControlKnobLow_22               matlab.ui.control.NumericEditField
        ControlKnobLabel_22             matlab.ui.control.Label
        ControlKnob_22                  matlab.ui.control.Knob
        ControlKnobPanel_21             matlab.ui.container.Panel
        ControlKnobHigh_21              matlab.ui.control.NumericEditField
        ControlKnobLow_21               matlab.ui.control.NumericEditField
        ControlKnobLabel_21             matlab.ui.control.Label
        ControlKnob_21                  matlab.ui.control.Knob
        NetworkDropDown                 matlab.ui.control.DropDown
        NetworkDropDownLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------------------------------------
        % Properties of the Graphical Interface
        %-----------------------------------------------------------------------------------------------
        NetworkType                                 % String: Type of Network (Open Loop, AIC, ...)
        Nmax_PlantKnobs = 16;                        % Number: Maximum Allowed Number of Plant Knobs
        Nmax_ControlKnobs = 30;                     % Number: Maximum Allowed Number of Controller Knobs
        ImageFileName                               % String: File Path and Name of the Image
        TuningCurvesPanel_Flag                      % String: Visibility of Tuning Curve Panel (on or off)
        N_PlantKnobs                                % Number: Number of Plant Knobs
        PlantKnobs_Labels                           % Cell Array of Strings: Labels of Plant Knobs
        PlantKnobs_InitialValues                    % Array: Initial Values of Plant Knobs
        PlantKnobs_InitialRanges                    % Array: Initial Ranges of Plant Knobs
        N_ControlKnobs                              % Number: Number of Control Knobs
        ControlKnobs_Labels                         % Cell Array of Strings: Labels of Control Knobs
        ControlKnobs_InitialValues                  % Array: Initial Values of Control Knobs
        ControlKnobs_InitialRanges                  % Array: Initial Ranges of Control Knobs
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        %-----------------------------------------------------------------------------------------------
        % Properties of the Reaction Network
        %-----------------------------------------------------------------------------------------------
        PlantParameters                             % Structure: Parameters of the Plant    
        ControlParameters                           % Structure: Parameters of the Controller
        DisturbanceParameters                       % Structure: Parameters of the Disturbances
        PropensityFunction                          % Function Handle: Handle to propensity function written in separate function file
        StoichiometryMatrix                         % Array: Stoichiometry Matrix
        InitialCondition                            % Array: Vector of Initial Condition
        Names_Deterministic                         % Cell Array of Strings: Names of Species Concentrations
        Names_Stochastic                            % Cell Array of Strings: Names of Species Copy Numbers
        OutputIndex                                 % Integer: Index of the Output in the State Variable
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        %-----------------------------------------------------------------------------------------------
        % Simulation Variables
        %-----------------------------------------------------------------------------------------------
        DeterministicSimulation
        StochasticSimulation
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        %-----------------------------------------------------------------------------------------------
        % Graphics Properties
        %-----------------------------------------------------------------------------------------------
        PlotParameters = struct('FontSize', 15, 'LineWidth', 2);
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    
        %-----------------------------------------------------------------------------------------------
        % Networks
        %-----------------------------------------------------------------------------------------------
        Networks = NetworkConfiguration_Class();         
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    
    end
    
    methods (Access = private)
        %-----------------------------------------------------------------------------------------------
        % Set up the Network
        %-----------------------------------------------------------------------------------------------
        function Define_Scenario(app)
            for i = 1 : length(app.Networks)
                if strcmp(app.NetworkType, app.Networks(i).Network_Name)
                    MyNetwork = app.Networks(i);
                    break;
                end
            end
            % Image
            app.ImageFileName = MyNetwork.ImageFileName;
            % Plant Parameters
            app.PlantParameters = MyNetwork.PlantParameters;
            % Plant Knobs
            app.N_PlantKnobs = MyNetwork.N_PlantKnobs;
            app.PlantKnobs_Labels = MyNetwork.PlantKnobs_Labels;  
            app.PlantKnobs_InitialValues = MyNetwork.PlantKnobs_InitialValues;
            app.PlantKnobs_InitialRanges = MyNetwork.PlantKnobs_InitialRanges;
            % Control Parameters
            app.ControlParameters = MyNetwork.ControlParameters;
            % Control Knobs
            app.N_ControlKnobs = MyNetwork.N_ControlKnobs;
            app.ControlKnobs_Labels = MyNetwork.ControlKnobs_Labels;
            app.ControlKnobs_InitialValues = MyNetwork.ControlKnobs_InitialValues;
            app.ControlKnobs_InitialRanges = MyNetwork.ControlKnobs_InitialRanges;
            % Tuning Curves Panel
            app.TuningCurvesPanel_Flag = MyNetwork.TuningCurvesPanel_Flag;
            % Propensity Function
            app.PropensityFunction = MyNetwork.PropensityFunction;
            % Stoichiometry Matrix
            app.StoichiometryMatrix = MyNetwork.StoichiometryMatrix;
            % Initial Condition
            app.InitialCondition = MyNetwork.InitialCondition; 
            % Names of State Variables (for each Species)
            app.Names_Deterministic = MyNetwork.Names_Deterministic;
            app.Names_Stochastic = MyNetwork.Names_Stochastic;
            % Output Index 
            app.OutputIndex = MyNetwork.OutputIndex;
            % Network Layout
            N_Plant = length(MyNetwork.PlantSpecies);
            N_Controller = length(MyNetwork.ControllerSpecies);
            K_Plant = length(MyNetwork.PlantPropensities);
            K_Controller = length(MyNetwork.ControllerPropensities);
            Species = [MyNetwork.PlantSpecies, MyNetwork.ControllerSpecies];
            app.NamesListPlant.Items = {};
            for i = 1 : N_Plant
                app.NamesListPlant.Items{i} = num2str(MyNetwork.PlantSpecies{i});
            end 
            app.OutputSpeciesDropDown.Items = MyNetwork.PlantSpecies(MyNetwork.OutputIndex);
            app.OutputSpeciesDropDown.Value = MyNetwork.PlantSpecies{MyNetwork.OutputIndex};
            app.NamesListController.Items = {};
            for i = 1 : N_Controller
                app.NamesListController.Items{i} = num2str(MyNetwork.ControllerSpecies{i});
            end 
            app.PlantReactionsTable.Data = {};
            for i = 1 : K_Plant
                [Reactants_Text, Products_Text] = Coeff2Rxn(MyNetwork.PlantCoefficients_Reactants(:,i), MyNetwork.PlantCoefficients_Products(:,i), Species);
                app.PlantReactionsTable.Data{i,1} = Reactants_Text;
                app.PlantReactionsTable.Data{i,2} = Products_Text;
                app.PlantReactionsTable.Data{i,3} = MyNetwork.PlantPropensities{i};
            end
            app.ControllerReactionsTable.Data = {};
            for i = 1 : K_Controller
                [Reactants_Text, Products_Text] = Coeff2Rxn(MyNetwork.ControllerCoefficients_Reactants(:,i), MyNetwork.ControllerCoefficients_Products(:,i), Species);
                app.ControllerReactionsTable.Data{i,1} = Reactants_Text;
                app.ControllerReactionsTable.Data{i,2} = Products_Text;
                app.ControllerReactionsTable.Data{i,3} = MyNetwork.ControllerPropensities{i};
            end
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        %-----------------------------------------------------------------------------------------------
        % This function sets the layout. It is called when the controller type is changed
        %-----------------------------------------------------------------------------------------------
        function Set_Layout(app)
            % Visibitlity of Tuning Curves Panel
            app.TuningCurvesPanel.Visible = app.TuningCurvesPanel_Flag;
            % Visibility of Plant and Control Panels
            app.PlantPanel.Visible = 'on';
            app.ControlPanel.Visible = 'on';
            % Visibility of Plant Knob Panels
            for i = 1 : app.N_PlantKnobs
                eval(['app.PlantKnobPanel_', num2str(i), '.Visible = ''on'';']);
            end
            for i = app.N_PlantKnobs+1 : app.Nmax_PlantKnobs
                eval(['app.PlantKnobPanel_', num2str(i), '.Visible = ''off'';']);
            end
            % Visibility of Control Knob Panels
            for i = 1 : app.N_ControlKnobs
                eval(['app.ControlKnobPanel_', num2str(i), '.Visible = ''on'';']);
            end
            for i = app.N_ControlKnobs+1 : app.Nmax_ControlKnobs
                eval(['app.ControlKnobPanel_', num2str(i), '.Visible = ''off'';']);
            end
            % Labels, Initial Values, and Ranges of Plant Knobs
            for i = 1 : app.N_PlantKnobs
                eval(['app.PlantKnobLabel_', num2str(i), '.Text = app.PlantKnobs_Labels{', num2str(i), '};']);
                eval(['app.PlantKnob_', num2str(i), '.Limits = app.PlantKnobs_InitialRanges(:,', num2str(i), ');']);
                eval(['app.PlantKnob_', num2str(i), '.Value = app.PlantKnobs_InitialValues(', num2str(i), ');']);
                eval(['app.PlantKnobLow_', num2str(i), '.Value = app.PlantKnobs_InitialRanges(1,', num2str(i), ');']);
                eval(['app.PlantKnobHigh_', num2str(i), '.Value = app.PlantKnobs_InitialRanges(2,', num2str(i), ');']);
            end
            % Labels, Initial Values, and Ranges of Control Knobs
            for i = 1 : app.N_ControlKnobs
                eval(['app.ControlKnobLabel_', num2str(i), '.Text = app.ControlKnobs_Labels{', num2str(i), '};']);
                eval(['app.ControlKnob_', num2str(i), '.Limits = app.ControlKnobs_InitialRanges(:,', num2str(i), ');']);
                eval(['app.ControlKnob_', num2str(i), '.Value = app.ControlKnobs_InitialValues(', num2str(i), ');']);
                eval(['app.ControlKnobLow_', num2str(i), '.Value = app.ControlKnobs_InitialRanges(1,', num2str(i), ');']);
                eval(['app.ControlKnobHigh_', num2str(i), '.Value = app.ControlKnobs_InitialRanges(2,', num2str(i), ');']);
            end
            % Disturance & Tracking Panel
            if (app.N_PlantKnobs + app.N_ControlKnobs >= 3)
                app.DropDown_Param_1.Visible = 'on';
                app.t1DisturbanceFactor_Param_1.Visible = 'on';
                app.t2DisturbanceFactor_Param_1.Visible = 'on';
                app.DropDown_Param_2.Visible = 'on';
                app.t1DisturbanceFactor_Param_2.Visible = 'on';
                app.t2DisturbanceFactor_Param_2.Visible = 'on';
                app.DropDown_Param_3.Visible = 'on';
                app.t1DisturbanceFactor_Param_3.Visible = 'on';
                app.t2DisturbanceFactor_Param_3.Visible = 'on';
                app.DropDown_Param_1.Items = [app.PlantKnobs_Labels, app.ControlKnobs_Labels];
                app.DropDown_Param_1.Value = app.DropDown_Param_1.Items{1};
                app.DropDown_Param_2.Items = [app.PlantKnobs_Labels, app.ControlKnobs_Labels];
                app.DropDown_Param_2.Value = app.DropDown_Param_1.Items{2};
                app.DropDown_Param_3.Items = [app.PlantKnobs_Labels, app.ControlKnobs_Labels];
                app.DropDown_Param_3.Value = app.DropDown_Param_1.Items{3};
            elseif (app.N_PlantKnobs + app.N_ControlKnobs == 2)
                app.DropDown_Param_1.Visible = 'on';
                app.t1DisturbanceFactor_Param_1.Visible = 'on';
                app.t2DisturbanceFactor_Param_1.Visible = 'on';
                app.DropDown_Param_2.Visible = 'on';
                app.t1DisturbanceFactor_Param_2.Visible = 'on';
                app.t2DisturbanceFactor_Param_2.Visible = 'on';
                app.DropDown_Param_3.Visible = 'off';
                app.t1DisturbanceFactor_Param_3.Visible = 'off';
                app.t2DisturbanceFactor_Param_3.Visible = 'off';
                app.DropDown_Param_1.Items = [app.PlantKnobs_Labels, app.ControlKnobs_Labels];
                app.DropDown_Param_1.Value = app.DropDown_Param_1.Items{1};
                app.DropDown_Param_2.Items = [app.PlantKnobs_Labels, app.ControlKnobs_Labels];
                app.DropDown_Param_2.Value = app.DropDown_Param_1.Items{2};
                app.DropDown_Param_3.Items = {'Dummy'};
                app.DropDown_Param_3.Value = 'Dummy';
            elseif (app.N_PlantKnobs + app.N_ControlKnobs == 1)
                app.DropDown_Param_1.Visible = 'on';
                app.t1DisturbanceFactor_Param_1.Visible = 'on';
                app.t2DisturbanceFactor_Param_1.Visible = 'on';
                app.DropDown_Param_2.Visible = 'off';
                app.t1DisturbanceFactor_Param_2.Visible = 'off';
                app.t2DisturbanceFactor_Param_2.Visible = 'off';
                app.DropDown_Param_3.Visible = 'off';
                app.t1DisturbanceFactor_Param_3.Visible = 'off';
                app.t2DisturbanceFactor_Param_3.Visible = 'off';
                app.DropDown_Param_1.Items = [app.PlantKnobs_Labels, app.ControlKnobs_Labels];
                app.DropDown_Param_1.Value = app.DropDown_Param_1.Items{1};
                app.DropDown_Param_2.Items = {'Dummy'};
                app.DropDown_Param_2.Value = 'Dummy';
                app.DropDown_Param_3.Items = {'Dummy'};
                app.DropDown_Param_3.Value = 'Dummy';
            end
            % Image
            if ~isempty(app.ImageFileName)
                app.Image.ImageSource = app.ImageFileName;
            end
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        %-----------------------------------------------------------------------------------------------
        % This function reads the system parameters (Control and Plant Panel Knobs)
        %-----------------------------------------------------------------------------------------------
        function ReadSystemParameters(app)
            FN_PlantParameters = fieldnames(app.PlantParameters);
            for i = 1 : app.N_PlantKnobs
                app.PlantParameters.(FN_PlantParameters{i}) = eval(['app.PlantKnob_', num2str(i), '.Value']);
            end
            FN_ControlParameters = fieldnames(app.ControlParameters);
            for i = 1 : app.N_ControlKnobs
                app.ControlParameters.(FN_ControlParameters{i}) = eval(['app.ControlKnob_', num2str(i), '.Value']);
            end
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        %-----------------------------------------------------------------------------------------------
        % This function reads the parameters from the disturbance Panel
        %-----------------------------------------------------------------------------------------------
        function ReadDisturbanceParameters(app)
            app.DisturbanceParameters.t1 = app.t1Disturbance.Value;
            app.DisturbanceParameters.t2 = app.t2Disturbance.Value;
            app.DisturbanceParameters.Param1_t1 = app.t1DisturbanceFactor_Param_1.Value;
            app.DisturbanceParameters.Param1_t2 = app.t2DisturbanceFactor_Param_1.Value;
            app.DisturbanceParameters.Param2_t1 = app.t1DisturbanceFactor_Param_2.Value;
            app.DisturbanceParameters.Param2_t2 = app.t2DisturbanceFactor_Param_2.Value;
            app.DisturbanceParameters.Param3_t1 = app.t1DisturbanceFactor_Param_3.Value;
            app.DisturbanceParameters.Param3_t2 = app.t2DisturbanceFactor_Param_3.Value;
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function ClearWorkSpace(app)
            % Image
            app.ImageFileName = {}; 
            % Plant Parameters
            app.PlantParameters = {};
            % Plant Knobs
            app.N_PlantKnobs = {};
            app.PlantKnobs_Labels = {};  
            app.PlantKnobs_InitialValues = {};
            app.PlantKnobs_InitialRanges = {};
            % Control Parameters
            app.ControlParameters = {};
            % Control Knobs
            app.N_ControlKnobs = {}; 
            app.ControlKnobs_Labels = {};  
            app.ControlKnobs_InitialValues = {};
            app.ControlKnobs_InitialRanges = {};
            % Tuning Curves Panel
            app.TuningCurvesPanel_Flag = {};
            % Propensity Function
            app.PropensityFunction = {};
            % Stoichiometry Matrix
            app.StoichiometryMatrix = {};                     
            % Initial Condition
            app.InitialCondition = {}; 
            % Names of State Variables (for each Species)
            app.Names_Deterministic = {};
            app.Names_Stochastic = {};
            % Output Index 
            app.OutputIndex = {};
        end
        
        function CreateStartupNetworks(app)
        % Open Loop
            % Network Name
            app.Networks(1).Network_Name = 'OL_GeneExp';
            % Image
            app.Networks(1).ImageFileName = 'Network Types/OL Gene Expression/OL Gene Expression.svg';
            % Plant Parameters
            app.Networks(1).PlantParameters = struct('gamma_1', [], 'gamma_2', [], 'k', [], 'k_1', []);
            % Plant Knobs
            app.Networks(1).N_PlantKnobs = 4;
            app.Networks(1).PlantKnobs_Labels = {'gamma_1', 'gamma_2', 'k', 'k_1'};  
            app.Networks(1).PlantKnobs_InitialValues = [0.1,        5,          2,          5];
            app.Networks(1).PlantKnobs_InitialRanges = [0,          0,          0,          0; ...
                                                        0.3,        10,         5,          10];
            % Control Parameters
            app.Networks(1).ControlParameters = struct();
            % Control Knobs
            app.Networks(1).N_ControlKnobs = 0;
            % Tuning Curves Panel
            app.Networks(1).TuningCurvesPanel_Flag = 'off';
            % Propensity Function
            app.Networks(1).PropensityFunction = @PropensityFunction_OL;
            % Stoichiometry Matrix
            app.Networks(1).StoichiometryMatrix = StoichiometryMatrix_OL();
            % Initial Condition
            app.Networks(1).InitialCondition = [0; 0]; 
            % Names of State Variables (for each Species)
            app.Networks(1).Names_Deterministic = {'x_1', 'x_2'};
            app.Networks(1).Names_Stochastic = {'X_1', 'X_2'};
            % Output Index 
            app.Networks(1).OutputIndex = 2; 
            app.NetworkDropDown.Items{1} = app.Networks(1).Network_Name;
            % Species
            app.Networks(1).PlantSpecies = {'X_1', 'X_2'};
            app.Networks(1).ControllerSpecies = {};
            % Reactions
            app.Networks(1).PlantCoefficients_Reactants = [0, 1, 1, 0; 0, 0, 0, 1];
            app.Networks(1).PlantCoefficients_Products = [1, 1, 0, 0; 0, 1, 0, 0];
            app.Networks(1).ControllerCoefficients_Reactants = [];
            app.Networks(1).ControllerCoefficients_Products = [];
            % Propensities
            app.Networks(1).PlantPropensities = {'k', 'k_1*X_1', 'gamma_1*X_1', 'gamma_2*X_2'};
            app.Networks(1).ControllerPropensities = {};
            % Parameters
            app.Networks(1).PlantParameters_String = {'gamma_1', 'gamma_2', 'k', 'k_1'};
            app.Networks(1).ControllerParameters_String = {};
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'BioMolecular Controllers';
            % Add the folders to the Matlab Path
            Folder = fileparts(which('BMC.mlapp'));
            addpath(genpath(Folder));
            % Create Startup Networks
            CreateStartupNetworks(app);
            % Select the Open Loop Network
            app.NetworkDropDown.Value = 'OL_GeneExp';
            app.NetworkType = 'OL_GeneExp';
            Define_Scenario(app);
            Set_Layout(app);
            % Set Status to Idle
            app.StatusProcessingLabel.Text = 'Status: Idle';
            % OL Feedback Network
            load('Network Types/OL Feedback Network/ConfigurationFile_OL_FeedbackNetwork.mat', 'MyNetwork');
            app.NetworkDropDown.Items{end+1} = MyNetwork.Network_Name;
            app.Networks(end+1) = MyNetwork;
            % aPI Add Gene Expression Network
            load('Network Types/aPI Add Gene Expression/ConfigurationFile_aPI_Add_GeneExp.mat', 'MyNetwork');
            app.NetworkDropDown.Items{end+1} = MyNetwork.Network_Name;
            app.Networks(end+1) = MyNetwork;
            % aPI Mult Gene Expression Network
            load('Network Types/aPI Mult Gene Expression/ConfigurationFile_aPI_Mult_GeneExp.mat', 'MyNetwork');
            app.NetworkDropDown.Items{end+1} = MyNetwork.Network_Name;
            app.Networks(end+1) = MyNetwork;
            % aPI Deg Gene Expression Network
            load('Network Types/aPI Deg Gene Expression/ConfigurationFile_aPI_Deg_GeneExp.mat', 'MyNetwork');
            app.NetworkDropDown.Items{end+1} = MyNetwork.Network_Name;
            app.Networks(end+1) = MyNetwork;
            % aPID2 Deg Gene Expression Network
            load('Network Types/aPID2 Deg Gene Expression/ConfigurationFile_aPID2_Deg_GeneExp.mat', 'MyNetwork');
            app.NetworkDropDown.Items{end+1} = MyNetwork.Network_Name;
            app.Networks(end+1) = MyNetwork;
            % aPID3 Deg Gene Expression Network
            load('Network Types/aPID3 Deg Gene Expression/ConfigurationFile_aPID3_Deg_GeneExp.mat', 'MyNetwork');
            app.NetworkDropDown.Items{end+1} = MyNetwork.Network_Name;
            app.Networks(end+1) = MyNetwork;
            % aPID4 Deg Gene Expression Network
            load('Network Types/aPID4 Deg Gene Expression/ConfigurationFile_aPID4_Deg_GeneExp.mat', 'MyNetwork');
            app.NetworkDropDown.Items{end+1} = MyNetwork.Network_Name;
            app.Networks(end+1) = MyNetwork;
            % aPID2 Deg Feedback Network
            load('Network Types/aPID2 Deg Feedback Network/ConfigurationFile_aPID2_Deg_FeedbackNet.mat', 'MyNetwork');
            app.NetworkDropDown.Items{end+1} = MyNetwork.Network_Name;
            app.Networks(end+1) = MyNetwork;
            % aPID3 Deg Feedback Network
            load('Network Types/aPID3 Deg Feedback Network/ConfigurationFile_aPID3_Deg_FeedbackNet.mat', 'MyNetwork');
            app.NetworkDropDown.Items{end+1} = MyNetwork.Network_Name;
            app.Networks(end+1) = MyNetwork;
            % aPID4 Deg Feedback Network
            load('Network Types/aPID4 Deg Feedback Network/ConfigurationFile_aPID4_Deg_FeedbackNet.mat', 'MyNetwork');
            app.NetworkDropDown.Items{end+1} = MyNetwork.Network_Name;
            app.Networks(end+1) = MyNetwork;
            % aPID4 Hybrid Deg Feedback Network
            load('Network Types/aPID4 Hybrid Deg Feedback Network/ConfigurationFile_aPID4_Hybrid_Deg_FeedbackNet.mat', 'MyNetwork');
            app.NetworkDropDown.Items{end+1} = MyNetwork.Network_Name;
            app.Networks(end+1) = MyNetwork;
        end

        % Button pushed function: RunDeterministicButton
        function RunDeterministicButtonPushed(app, event)
            % Clear the Previous Deterministic Simulation
            clear app.DeterministicSimulation;
            % Change Status to Processing
            app.StatusProcessingLabel.Text = 'Status: Processing...';
            pause(0.01);
            % Read Deterministic Simulation Settings
            N = round(app.TimeSamplesSlider.Value);
            tf = app.DeterministicSimulationTime.Value;
            Solver = app.SolverDropDown.Value;
            % Read System Parameters
            ReadSystemParameters(app);
            % Collect the Closed Loop Network Parameters
            NetworkParameters = app.PlantParameters;
            FN_Controller = fieldnames(app.ControlParameters);
            for i = 1:length(FN_Controller)
                NetworkParameters.(FN_Controller{i}) = app.ControlParameters.(FN_Controller{i});
            end  
            NetworkParameters.Dummy = NaN;
            % Disturbances
            ReadDisturbanceParameters(app);
            FN_Plant = fieldnames(app.PlantParameters);
            FN = [FN_Plant; FN_Controller];
            t1 = app.DisturbanceParameters.t1;
            t2 = app.DisturbanceParameters.t2;
            DisturbedParam1 = 'Dummy';
            if sum(ismember([app.PlantKnobs_Labels, app.ControlKnobs_Labels], app.DropDown_Param_1.Value))
                DisturbedParam1 = FN{ismember([app.PlantKnobs_Labels, app.ControlKnobs_Labels], app.DropDown_Param_1.Value)};
            end
            DisturbedParam2 = 'Dummy';
            if sum(ismember([app.PlantKnobs_Labels, app.ControlKnobs_Labels], app.DropDown_Param_2.Value))
                DisturbedParam2 = FN{ismember([app.PlantKnobs_Labels, app.ControlKnobs_Labels], app.DropDown_Param_2.Value)};
            end
            DisturbedParam3 = 'Dummy';
            if sum(ismember([app.PlantKnobs_Labels, app.ControlKnobs_Labels], app.DropDown_Param_3.Value))
                DisturbedParam3 = FN{ismember([app.PlantKnobs_Labels, app.ControlKnobs_Labels], app.DropDown_Param_3.Value)};
            end
            NetworkParameters1 = NetworkParameters;
            NetworkParameters2 = NetworkParameters;
            NetworkParameters1.(DisturbedParam1) = NetworkParameters.(DisturbedParam1) * app.DisturbanceParameters.Param1_t1;
            NetworkParameters1.(DisturbedParam2) = NetworkParameters.(DisturbedParam2) * app.DisturbanceParameters.Param2_t1;
            NetworkParameters1.(DisturbedParam3) = NetworkParameters.(DisturbedParam3) * app.DisturbanceParameters.Param3_t1;
            NetworkParameters2.(DisturbedParam1) = NetworkParameters.(DisturbedParam1) * app.DisturbanceParameters.Param1_t2;
            NetworkParameters2.(DisturbedParam2) = NetworkParameters.(DisturbedParam2) * app.DisturbanceParameters.Param2_t2;
            NetworkParameters2.(DisturbedParam3) = NetworkParameters.(DisturbedParam3) * app.DisturbanceParameters.Param3_t2;
            % Deterministic Simulation
            if strcmp(app.SwitchDisturbance.Value, 'Off') || ...
                    (app.CheckBox_1.Value == false && app.CheckBox_2.Value == false) || ...
                    (app.CheckBox_1.Value == true && app.CheckBox_2.Value == false && (t1 <= 0 || t1 >=tf)) || ...
                    (app.CheckBox_1.Value == true && app.CheckBox_2.Value == true && (t1 <= 0 || t1 >=tf) && (t2 <= 0 || t2 >=tf))
                TStart = tic;
                % No Disturbance
                [time_vector, x] = DSA(app.StoichiometryMatrix, app.PropensityFunction, NetworkParameters, app.InitialCondition, tf, N, Solver);
                SimTime = toc(TStart);
            elseif (app.CheckBox_1.Value == true && app.CheckBox_2.Value == false) || ...
                   (app.CheckBox_1.Value == true && app.CheckBox_2.Value == true && (t2 <= t1 || t2 >=tf) )
                N0 = round(t1 * N /tf);
                N1 = N - N0;
                TStart = tic;
                % Before the Disturbances
                [time_vector1, x1] = DSA(app.StoichiometryMatrix, app.PropensityFunction, NetworkParameters, app.InitialCondition, t1, N0, Solver);
                % First Disturbance at time t1
                [time_vector2, x2] = DSA(app.StoichiometryMatrix, app.PropensityFunction, NetworkParameters1, x1(:,end), tf - t1, N1, Solver);
                SimTime = toc(TStart);
                % Construct Full Solution
                time_vector = [time_vector1, t1 + time_vector2(2:end)];
                x = [x1, x2(:,2:end)];
            elseif (app.CheckBox_1.Value == true && app.CheckBox_2.Value == true)
                N0 = round(t1 * N /tf);
                N1 = round((t2-t1) * N /tf);
                N2 = N - N0 - N1;
                TStart = tic;
                % Before the Disturbances
                [time_vector1, x1] = DSA(app.StoichiometryMatrix, app.PropensityFunction, NetworkParameters, app.InitialCondition, t1, N0, Solver);
                % First Disturbance at time t1
                [time_vector2, x2] = DSA(app.StoichiometryMatrix, app.PropensityFunction, NetworkParameters1, x1(:,end), t2-t1, N1, Solver);
                % Second Disturbance at time t2
                [time_vector3, x3] = DSA(app.StoichiometryMatrix, app.PropensityFunction, NetworkParameters2, x2(:,end), tf - t2, N2, Solver);
                SimTime = toc(TStart);
                % Construct Full Solution
                time_vector = [time_vector1, t1 + time_vector2(2:end), t2 + time_vector3(2:end)];
                x = [x1, x2(:,2:end), x3(:,2:end)];
            end
            % Output Plot
            plot(app.DeterministicResponseAxes, time_vector, x(app.OutputIndex,:), 'LineWidth', app.PlotParameters.LineWidth, 'Color', 'black');
            % Save Simulation Results
            app.DeterministicSimulation.time_vector = time_vector;
            app.DeterministicSimulation.x = x;
            % Computation Time
            app.ComputationTime.Text = [num2str(SimTime), ' s'];
            % Change Status to Idle
            app.StatusProcessingLabel.Text = 'Status: Idle...';
        end

        % Value changed function: NetworkDropDown
        function NetworkDropDownValueChanged(app, event)
            ClearWorkSpace(app);
            app.NetworkType = app.NetworkDropDown.Value;
            Define_Scenario(app);
            Set_Layout(app);
        end

        % Value changed function: SwitchDisturbance
        function SwitchDisturbanceValueChanged(app, event)
            if strcmp(app.SwitchDisturbance.Value, 'On')
                app.Lamp.Color = [1, 0, 0];
                app.CheckBox_1.Enable = true;
                app.CheckBox_2.Enable = false;
                app.CheckBox_1.Value = false;
                app.CheckBox_2.Value = false;
            else
                app.Lamp.Color = [0, 1, 0];
                app.CheckBox_1.Value = false;
                app.CheckBox_2.Value = false;
                app.CheckBox_1.Enable = false;
                app.CheckBox_2.Enable = false;
                app.t1Disturbance.Enable = false;
                app.t2Disturbance.Enable = false;
                app.t1DisturbanceFactor_Param_1.Enable = false;
                app.t2DisturbanceFactor_Param_1.Enable = false;
                app.t1DisturbanceFactor_Param_3.Enable = false;
                app.t2DisturbanceFactor_Param_3.Enable = false;
                app.t1DisturbanceFactor_Param_2.Enable = false;
                app.t2DisturbanceFactor_Param_2.Enable = false;
            end
            
        end

        % Value changed function: CheckBox_1
        function CheckBox_1ValueChanged(app, event)
            if app.CheckBox_1.Value == true
                app.CheckBox_2.Enable = true;
                app.t1Disturbance.Enable = true;
                app.t1DisturbanceFactor_Param_1.Enable = true;
                app.t1DisturbanceFactor_Param_3.Enable = true;
                app.t1DisturbanceFactor_Param_2.Enable = true;
             else
                app.CheckBox_2.Value = false;
                app.CheckBox_2.Enable = false;
                app.t1Disturbance.Enable = false;
                app.t2Disturbance.Enable = false;
                app.t1DisturbanceFactor_Param_1.Enable = false;
                app.t2DisturbanceFactor_Param_1.Enable = false;
                app.t1DisturbanceFactor_Param_3.Enable = false;
                app.t2DisturbanceFactor_Param_3.Enable = false;
                app.t1DisturbanceFactor_Param_2.Enable = false;
                app.t2DisturbanceFactor_Param_2.Enable = false;
            end
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            if app.CheckBox_2.Value == true
                app.t2Disturbance.Enable = true;
                app.t2DisturbanceFactor_Param_1.Enable = true;
                app.t2DisturbanceFactor_Param_3.Enable = true;
                app.t2DisturbanceFactor_Param_2.Enable = true;
            else
                app.t2Disturbance.Enable = false;
                app.t2DisturbanceFactor_Param_1.Enable = false;
                app.t2DisturbanceFactor_Param_3.Enable = false;
                app.t2DisturbanceFactor_Param_2.Enable = false;
            end
        end

        % Button pushed function: RunStochasticButton
        function RunStochasticButtonPushed(app, event)
            % Clear the Previous Stochastic Simulation
            clear app.StochasticSimulation;
            % Change Status to Processing
            app.StatusProcessingLabel.Text = 'Status: Processing...';
            pause(0.01);
            % Read Stochastic Simulation Settings
            N_MaxRxnEvents = round(app.MaximumNumberofEvents.Value);
            N_Trajectories = round(app.NumberofTrajectoriesSlider.Value);
            tf = app.StochasticSimulationTime.Value;
            % Read System Parameters
            ReadSystemParameters(app);
            % Collect the Closed Loop Network Parameters
            NetworkParameters = app.PlantParameters;
            FN_Controller = fieldnames(app.ControlParameters);
            for i = 1:length(FN_Controller)
                NetworkParameters.(FN_Controller{i}) = app.ControlParameters.(FN_Controller{i});
            end
            NetworkParameters.Dummy = NaN;
            % Extract Stoichiometry Matrix, Propensity Function and Initial Condition
            SM = app.StoichiometryMatrix;
            PF = app.PropensityFunction;
            IC = app.InitialCondition;
            % Disturbances
            ReadDisturbanceParameters(app);
            FN_Plant = fieldnames(app.PlantParameters);
            FN = [FN_Plant; FN_Controller];
            t1 = app.DisturbanceParameters.t1;
            t2 = app.DisturbanceParameters.t2;
            DisturbedParam1 = 'Dummy';
            if sum(ismember([app.PlantKnobs_Labels, app.ControlKnobs_Labels], app.DropDown_Param_1.Value))
                DisturbedParam1 = FN{ismember([app.PlantKnobs_Labels, app.ControlKnobs_Labels], app.DropDown_Param_1.Value)};
            end
            DisturbedParam2 = 'Dummy';
            if sum(ismember([app.PlantKnobs_Labels, app.ControlKnobs_Labels], app.DropDown_Param_2.Value))
                DisturbedParam2 = FN{ismember([app.PlantKnobs_Labels, app.ControlKnobs_Labels], app.DropDown_Param_2.Value)};
            end
            DisturbedParam3 = 'Dummy';
            if sum(ismember([app.PlantKnobs_Labels, app.ControlKnobs_Labels], app.DropDown_Param_3.Value))
                DisturbedParam3 = FN{ismember([app.PlantKnobs_Labels, app.ControlKnobs_Labels], app.DropDown_Param_3.Value)};
            end
            NetworkParameters1 = NetworkParameters;
            NetworkParameters2 = NetworkParameters;
            NetworkParameters1.(DisturbedParam1) = NetworkParameters.(DisturbedParam1) * app.DisturbanceParameters.Param1_t1;
            NetworkParameters1.(DisturbedParam2) = NetworkParameters.(DisturbedParam2) * app.DisturbanceParameters.Param2_t1;
            NetworkParameters1.(DisturbedParam3) = NetworkParameters.(DisturbedParam3) * app.DisturbanceParameters.Param3_t1;
            NetworkParameters2.(DisturbedParam1) = NetworkParameters.(DisturbedParam1) * app.DisturbanceParameters.Param1_t2;
            NetworkParameters2.(DisturbedParam2) = NetworkParameters.(DisturbedParam2) * app.DisturbanceParameters.Param2_t2;
            NetworkParameters2.(DisturbedParam3) = NetworkParameters.(DisturbedParam3) * app.DisturbanceParameters.Param3_t2;
            % Stochastic Simulation
            T = cell(N_Trajectories,1);
            X = cell(N_Trajectories,1);
            if strcmp(app.SwitchDisturbance.Value, 'Off') || ...
                    (app.CheckBox_1.Value == false && app.CheckBox_2.Value == false) || ...
                    (app.CheckBox_1.Value == true && app.CheckBox_2.Value == false && (t1 <= 0 || t1 >=tf)) || ...
                    (app.CheckBox_1.Value == true && app.CheckBox_2.Value == true && (t1 <= 0 || t1 >=tf) && (t2 <= 0 || t2 >=tf))
                TStart = tic;
                % No Disturbance
                if app.ParallelComputingToolboxCheckBox.Value == 1
                    parfor i = 1 : N_Trajectories
                        [T{i}, X{i}] = SSA(PF, SM, NetworkParameters, IC, tf, N_MaxRxnEvents);
                    end
                else
                    for i = 1 : N_Trajectories
                        [T{i}, X{i}] = SSA(PF, SM, NetworkParameters, IC, tf, N_MaxRxnEvents);
                    end
                end
                SimTime = toc(TStart);
            elseif (app.CheckBox_1.Value == true && app.CheckBox_2.Value == false) || ...
                   (app.CheckBox_1.Value == true && app.CheckBox_2.Value == true && (t2 <= t1 || t2 >=tf))
                TStart = tic;
                % Before the  Disturbances
                T1 = cell(N_Trajectories,1);
                X1 = cell(N_Trajectories,1);
                if app.ParallelComputingToolboxCheckBox.Value == 1
                    parfor i = 1 : N_Trajectories
                        [T1{i}, X1{i}] = SSA(PF, SM, NetworkParameters, IC, t1, N_MaxRxnEvents);
                    end
                else
                    for i = 1 : N_Trajectories
                        [T1{i}, X1{i}] = SSA(PF, SM, NetworkParameters, IC, t1, N_MaxRxnEvents);
                    end
                end
                % First Disturbance at time t1
                T2 = cell(N_Trajectories,1);
                X2 = cell(N_Trajectories,1);
                if app.ParallelComputingToolboxCheckBox.Value == 1
                    parfor i = 1 : N_Trajectories
                        [T2{i}, X2{i}] = SSA(PF, SM, NetworkParameters1, X1{i}(:,end), tf-t1, N_MaxRxnEvents);
                    end
                else
                    for i = 1 : N_Trajectories
                        [T2{i}, X2{i}] = SSA(PF, SM, NetworkParameters1, X1{i}(:,end), tf-t1, N_MaxRxnEvents);
                    end
                end
                SimTime = toc(TStart);
                % Construct Full Solution
                for i = 1 : N_Trajectories
                    T{i} = [T1{i}, t1 + T2{i}];
                    X{i} = [X1{i}, X2{i}];
                end                
            elseif (app.CheckBox.Value == true && app.CheckBox_2.Value == true)
                TStart = tic;
                % Before the Disturbances
                T1 = cell(N_Trajectories,1);
                X1 = cell(N_Trajectories,1);
                if app.ParallelComputingToolboxCheckBox.Value == 1
                    parfor i = 1 : N_Trajectories
                        [T1{i}, X1{i}] = SSA(PF, SM, NetworkParameters, IC, t1, N_MaxRxnEvents);
                    end
                else
                    for i = 1 : N_Trajectories
                        [T1{i}, X1{i}] = SSA(PF, SM, NetworkParameters, IC, t1, N_MaxRxnEvents);
                    end
                end
                % First Disturbance at time t1
                T2 = cell(N_Trajectories,1);
                X2 = cell(N_Trajectories,1);
                if app.ParallelComputingToolboxCheckBox.Value == 1
                    parfor i = 1 : N_Trajectories
                        [T2{i}, X2{i}] = SSA(PF, SM, NetworkParameters1, X1{i}(:,end), t2-t1, N_MaxRxnEvents);
                    end
                else
                    for i = 1 : N_Trajectories
                        [T2{i}, X2{i}] = SSA(PF, SM, NetworkParameters1, X1{i}(:,end), t2-t1, N_MaxRxnEvents);
                    end
                end
                % Second Disturbance at time t2
                T3 = cell(N_Trajectories,1);
                X3 = cell(N_Trajectories,1);
                if app.ParallelComputingToolboxCheckBox.Value == 1
                    parfor i = 1 : N_Trajectories
                        [T3{i}, X3{i}] = SSA(PF, SM, NetworkParameters2, X2{i}(:,end), tf-t2, N_MaxRxnEvents);
                    end
                else
                    for i = 1 : N_Trajectories
                        [T3{i}, X3{i}] = SSA(PF, SM, NetworkParameters2, X2{i}(:,end), tf-t2, N_MaxRxnEvents);
                    end
                end
                SimTime = toc(TStart);
                % Construct Full Solution
                for i = 1 : N_Trajectories
                    T{i} = [T1{i}, t1 + T2{i}, t2 + T3{i}];
                    X{i} = [X1{i}, X2{i}, X3{i}];
                end                
            end
            % Output Plots
            Colors = lines(10);
            cla(app.StochasticResponseAxes);
            hold(app.StochasticResponseAxes, 'on');
            for i = 1 : min(N_Trajectories, 50)
                plot(app.StochasticResponseAxes, T{i}, X{i}(app.OutputIndex,:), 'Color', [Colors(1,:), 0.2], 'LineWidth', app.PlotParameters.LineWidth); 
            end
            hold(app.StochasticResponseAxes, 'off');
            % Save Simulation Results
            app.StochasticSimulation.T = T;
            app.StochasticSimulation.X = X;
            % Computation Time
            app.ComputationTime.Text = [num2str(SimTime), ' s'];
            % Change Status to Idle
            app.StatusProcessingLabel.Text = 'Status: Idle...';
        end

        % Value changed function: PlantKnobLow_1
        function PlantKnobLow_1ValueChanged(app, event)
            app.PlantKnob_1.Limits(1) = app.PlantKnobLow_1.Value;
        end

        % Value changed function: PlantKnobHigh_1
        function PlantKnobHigh_1ValueChanged(app, event)
            app.PlantKnob_1.Limits(2) = app.PlantKnobHigh_1.Value;
        end

        % Value changed function: PlantKnobLow_2
        function PlantKnobLow_2ValueChanged(app, event)
            app.PlantKnob_2.Limits(1) = app.PlantKnobLow_2.Value;
        end

        % Value changed function: PlantKnobHigh_2
        function PlantKnobHigh_2ValueChanged(app, event)
            app.PlantKnob_2.Limits(2) = app.PlantKnobHigh_2.Value;
        end

        % Value changed function: PlantKnobLow_3
        function PlantKnobLow_3ValueChanged(app, event)
            app.PlantKnob_3.Limits(1) = app.PlantKnobLow_3.Value;
        end

        % Value changed function: PlantKnobHigh_3
        function PlantKnobHigh_3ValueChanged(app, event)
            app.PlantKnob_3.Limits(2) = app.PlantKnobHigh_3.Value;
        end

        % Value changed function: PlantKnobLow_4
        function PlantKnobLow_4ValueChanged(app, event)
            app.PlantKnob_4.Limits(1) = app.PlantKnobLow_4.Value;
        end

        % Value changed function: PlantKnobHigh_4
        function PlantKnobHigh_4ValueChanged(app, event)
            app.PlantKnob_4.Limits(2) = app.PlantKnobHigh_4.Value;
        end

        % Value changed function: ControlKnobLow_1
        function ControlKnobLow_1ValueChanged(app, event)
            app.ControlKnob_1.Limits(1) = app.ControlKnobLow_1.Value;
        end

        % Value changed function: ControlKnobHigh_1
        function ControlKnobHigh_1ValueChanged(app, event)
            app.ControlKnob_1.Limits(2) = app.ControlKnobHigh_1.Value;
        end

        % Value changed function: ControlKnobLow_2
        function ControlKnobLow_2ValueChanged(app, event)
            app.ControlKnob_2.Limits(1) = app.ControlKnobLow_2.Value;
        end

        % Value changed function: ControlKnobHigh_2
        function ControlKnobHigh_2ValueChanged(app, event)
            app.ControlKnob_2.Limits(2) = app.ControlKnobHigh_2.Value;
        end

        % Value changed function: ControlKnobLow_3
        function ControlKnobLow_3ValueChanged(app, event)
            app.ControlKnob_3.Limits(1) = app.ControlKnobLow_3.Value;
        end

        % Value changed function: ControlKnobHigh_3
        function ControlKnobHigh_3ValueChanged(app, event)
            app.ControlKnob_3.Limits(2) = app.ControlKnobHigh_3.Value;
        end

        % Value changed function: ControlKnobLow_4
        function ControlKnobLow_4ValueChanged(app, event)
            app.ControlKnob_4.Limits(1) = app.ControlKnobLow_4.Value;
        end

        % Value changed function: ControlKnobHigh_4
        function ControlKnobHigh_4ValueChanged(app, event)
            app.ControlKnob_4.Limits(2) = app.ControlKnobHigh_4.Value;
        end

        % Value changed function: ControlKnobLow_5
        function ControlKnobLow_5ValueChanged(app, event)
            app.ControlKnob_5.Limits(1) = app.ControlKnobLow_5.Value;
        end

        % Value changed function: ControlKnobHigh_5
        function ControlKnobHigh_5ValueChanged(app, event)
            app.ControlKnob_5.Limits(2) = app.ControlKnobHigh_5.Value;
        end

        % Value changed function: ControlKnobLow_6
        function ControlKnobLow_6ValueChanged(app, event)
            app.ControlKnob_6.Limits(1) = app.ControlKnobLow_6.Value;
        end

        % Value changed function: ControlKnobHigh_6
        function ControlKnobHigh_6ValueChanged(app, event)
            app.ControlKnob_6.Limits(2) = app.ControlKnobHigh_6.Value;
        end

        % Value changed function: ControlKnobLow_7
        function ControlKnobLow_7ValueChanged(app, event)
            app.ControlKnob_7.Limits(1) = app.ControlKnobLow_7.Value;
        end

        % Value changed function: ControlKnobHigh_7
        function ControlKnobHigh_7ValueChanged(app, event)
            app.ControlKnob_7.Limits(2) = app.ControlKnobHigh_7.Value;
        end

        % Value changed function: ControlKnobLow_8
        function ControlKnobLow_8ValueChanged(app, event)
            app.ControlKnob_8.Limits(1) = app.ControlKnobLow_8.Value;
        end

        % Value changed function: ControlKnobHigh_8
        function ControlKnobHigh_8ValueChanged(app, event)
            app.ControlKnob_8.Limits(2) = app.ControlKnobHigh_8.Value;
        end

        % Value changed function: ControlKnobLow_9
        function ControlKnobLow_9ValueChanged(app, event)
            app.ControlKnob_9.Limits(1) = app.ControlKnobLow_9.Value;
        end

        % Value changed function: ControlKnobHigh_9
        function ControlKnobHigh_9ValueChanged(app, event)
            app.ControlKnob_9.Limits(2) = app.ControlKnobHigh_9.Value;
        end

        % Value changed function: ControlKnobLow_10
        function ControlKnobLow_10ValueChanged(app, event)
            app.ControlKnob_10.Limits(1) = app.ControlKnobLow_10.Value;
        end

        % Value changed function: ControlKnobHigh_10
        function ControlKnobHigh_10ValueChanged(app, event)
            app.ControlKnob_10.Limits(2) = app.ControlKnobHigh_10.Value;
        end

        % Value changed function: TimeSamplesPanelLow
        function TimeSamplesPanelLowValueChanged(app, event)
            app.TimeSamplesSlider.Limits(1) = app.TimeSamplesPanelLow.Value;
        end

        % Value changed function: TimeSamplesPanelHigh
        function TimeSamplesPanelHighValueChanged(app, event)
            app.TimeSamplesSlider.Limits(2) = app.TimeSamplesPanelHigh.Value;
        end

        % Value changed function: StochasticTimeSamplesSliderLow
        function StochasticTimeSamplesSliderLowValueChanged(app, event)
            app.StochasticTimeSamplesSlider.Limits(1) = app.StochasticTimeSamplesSliderLow.Value;
        end

        % Value changed function: StochasticTimeSamplesSliderHigh
        function StochasticTimeSamplesSliderHighValueChanged(app, event)
            app.StochasticTimeSamplesSlider.Limits(2) = app.StochasticTimeSamplesSliderHigh.Value;
        end

        % Value changed function: TrajectoriesLow
        function TrajectoriesLowValueChanged(app, event)
            app.NumberofTrajectoriesSlider.Limits(1) = app.TrajectoriesLow.Value;
        end

        % Value changed function: TrajectoriesHigh
        function TrajectoriesHighValueChanged(app, event)
            app.NumberofTrajectoriesSlider.Limits(2) = app.TrajectoriesHigh.Value;
        end

        % Button pushed function: DeterministicPlots
        function DeterministicPlotsButtonPushed(app, event)
            DeterministicPlot(app.DeterministicSimulation.time_vector, app.DeterministicSimulation.x, app.PlotParameters, app.NetworkDropDown.Value, app.Names_Deterministic)
        end

        % Button pushed function: Statistics
        function StatisticsButtonPushed(app, event)
            % Change Status to Processing
            app.StatusProcessingLabel.Text = 'Status: Processing...';
            pause(0.01);
            % Read Time Samples
            N_Samples = app.StochasticTimeSamplesSlider.Value;
            % Compute Statistics
            TStart = tic;
            [time_vector, MeanX, VarX] = ComputeStatistics(app.StochasticSimulation.T, app.StochasticSimulation.X, N_Samples);
            SimTime = toc(TStart);
            % Output Plots
            hold(app.StochasticResponseAxes, 'on');
            plot(app.StochasticResponseAxes, time_vector, MeanX(app.OutputIndex,:), ...
                     'LineWidth', app.PlotParameters.LineWidth, 'Color', 'k');
%             plot(app.StochasticResponseAxes, time_vector, MeanX(app.OutputIndex,:) + 3*sqrt(VarX(app.OutputIndex,:)), ...
%                      'LineWidth', app.PlotParameters.LineWidth/4, 'Color', 'k');
            plot(app.StochasticResponseAxes, time_vector, VarX(app.OutputIndex,:), ...
                     'LineWidth', app.PlotParameters.LineWidth, 'Color', 'r');
            hold(app.StochasticResponseAxes, 'off');
            % Save Simulation Results
            app.StochasticSimulation.time_vector = time_vector;
            app.StochasticSimulation.MeanX = MeanX;
            app.StochasticSimulation.VarX = VarX;
            % Computation Time
            app.ComputationTime.Text = [num2str(SimTime), ' s'];
            % Change Status to Idle
            app.StatusProcessingLabel.Text = 'Status: Idle...';
        end

        % Button pushed function: StochasticPlots
        function StochasticPlotsButtonPushed(app, event)
            if app.withStatisticsCheckBox.Value == false 
                StochasticPlot(app.StochasticSimulation.T, app.StochasticSimulation.X, app.PlotParameters, app.NetworkDropDown.Value, app.Names_Stochastic);
           else
               StochasticPlot_Statistics(app.StochasticSimulation.T, app.StochasticSimulation.X, ...
                                         app.StochasticSimulation.time_vector, app.StochasticSimulation.MeanX, app.StochasticSimulation.VarX, ...
                                         app.PlotParameters, app.NetworkDropDown.Value, app.Names_Stochastic);
           end
        end

        % Button pushed function: ExportDeterministicButton
        function ExportDeterministicButtonPushed(app, event)
           CheckLabels = {'Save the time horizon to a variable named:'};
           VariableNames = {'t'};
           Items = {app.DeterministicSimulation.time_vector};
            for i = 1 : size(app.DeterministicSimulation.x,1)
                CheckLabels{i+1} = ['Save ', app.Names_Deterministic{i}, '(t) to a variable named:'];
                VariableNames{i+1} = app.Names_Deterministic{i};
                Items{i+1} = app.DeterministicSimulation.x(i,:);
            end
            export2wsdlg(CheckLabels, VariableNames, Items, 'Export to Workspace...');
        end

        % Button pushed function: ExportStochasticButton
        function ExportStochasticButtonPushed(app, event)
            N = size(app.StochasticSimulation.T,1);   
            CheckLabels = {'Save the time horizon of the means and variances to a variable named:'};
            VariableNames = {'t'};
            Items = {app.StochasticSimulation.time_vector};
            for i = 1 : size(app.StochasticSimulation.X{1},1)
                CheckLabels{i+1} = ['Save E[', app.Names_Stochastic{i}, '(t)] to a variable named:'];
                VariableNames{i+1} = ['Mean', app.Names_Stochastic{i}];
                Items{i+1} = app.StochasticSimulation.MeanX(i,:);
                CheckLabels{i+1+size(app.StochasticSimulation.X{1},1)} = ['Save Var[', app.Names_Stochastic{i}, '(t)] to a variable named:'];
                VariableNames{i+1+size(app.StochasticSimulation.X{1},1)} = ['Var', app.Names_Stochastic{i}];
                Items{i+1+size(app.StochasticSimulation.X{1},1)} = app.StochasticSimulation.VarX(i,:);
            end
            export2wsdlg(CheckLabels, VariableNames, Items, 'Export to Workspace...');
            clear CheckLabels VariableNames Items
            
            CheckLabels = {'Save the stochastic time horizons to a variable named:'};
            VariableNames = {'T'};
            Items = {app.StochasticSimulation.T};
            for i = 1 : size(app.StochasticSimulation.X{1},1)
                CheckLabels{i+1} = ['Save ', app.Names_Stochastic{i}, '(t) to a variable named:'];
                VariableNames{i+1} = app.Names_Stochastic{i};
                Temp = cell(N,1);
                for j = 1 : N
                    Temp{j} = app.StochasticSimulation.X{j}(i,:);
                end
                Items{i+1} = Temp;
                clear Temp;
            end
            export2wsdlg(CheckLabels, VariableNames, Items, 'Export to Workspace...');
        end

        % Value changed function: PlantKnobLow_5
        function PlantKnobLow_5ValueChanged(app, event)
            app.PlantKnob_5.Limits(1) = app.PlantKnobLow_5.Value;
        end

        % Value changed function: PlantKnobHigh_5
        function PlantKnobHigh_5ValueChanged(app, event)
            app.PlantKnob_5.Limits(2) = app.PlantKnobHigh_5.Value;
        end

        % Value changed function: PlantKnobLow_6
        function PlantKnobLow_6ValueChanged(app, event)
            app.PlantKnob_6.Limits(1) = app.PlantKnobLow_6.Value;
        end

        % Value changed function: PlantKnobHigh_6
        function PlantKnobHigh_6ValueChanged(app, event)
            app.PlantKnob_6.Limits(2) = app.PlantKnobHigh_6.Value;
        end

        % Value changed function: PlantKnobLow_7
        function PlantKnobLow_7ValueChanged(app, event)
            app.PlantKnob_7.Limits(1) = app.PlantKnobLow_7.Value;
        end

        % Value changed function: PlantKnobHigh_7
        function PlantKnobHigh_7ValueChanged(app, event)
            app.PlantKnob_7.Limits(2) = app.PlantKnobHigh_7.Value;
        end

        % Value changed function: PlantKnobLow_8
        function PlantKnobLow_8ValueChanged(app, event)
            app.PlantKnob_8.Limits(1) = app.PlantKnobLow_8.Value;
        end

        % Value changed function: PlantKnobHigh_8
        function PlantKnobHigh_8ValueChanged(app, event)
            app.PlantKnob_8.Limits(2) = app.PlantKnobHigh_8.Value;
        end

        % Value changed function: PlantKnobLow_9
        function PlantKnobLow_9ValueChanged(app, event)
            app.PlantKnob_9.Limits(1) = app.PlantKnobLow_9.Value;
        end

        % Value changed function: PlantKnobHigh_9
        function PlantKnobHigh_9ValueChanged(app, event)
            app.PlantKnob_9.Limits(2) = app.PlantKnobHigh_9.Value;
        end

        % Value changed function: PlantKnobLow_10
        function PlantKnobLow_10ValueChanged(app, event)
            app.PlantKnob_10.Limits(1) = app.PlantKnobLow_10.Value;
        end

        % Value changed function: PlantKnobHigh_10
        function PlantKnobHigh_10ValueChanged(app, event)
            app.PlantKnob_10.Limits(2) = app.PlantKnobHigh_10.Value;
        end

        % Value changed function: PlantKnobLow_11
        function PlantKnobLow_11ValueChanged(app, event)
            app.PlantKnob_11.Limits(1) = app.PlantKnobLow_11.Value;
        end

        % Value changed function: PlantKnobHigh_11
        function PlantKnobHigh_11ValueChanged(app, event)
            app.PlantKnob_11.Limits(2) = app.PlantKnobHigh_11.Value;
        end

        % Value changed function: PlantKnobLow_12
        function PlantKnobLow_12ValueChanged(app, event)
            app.PlantKnob_12.Limits(1) = app.PlantKnobLow_12.Value;
        end

        % Value changed function: PlantKnobHigh_12
        function PlantKnobHigh_12ValueChanged(app, event)
            app.PlantKnob_12.Limits(2) = app.PlantKnobHigh_12.Value;
        end

        % Value changed function: ControlKnobLow_11
        function ControlKnobLow_11ValueChanged(app, event)
            app.ControlKnob_11.Limits(1) = app.ControlKnobLow_11.Value;
        end

        % Value changed function: ControlKnobHigh_11
        function ControlKnobHigh_11ValueChanged(app, event)
            app.ControlKnob_11.Limits(2) = app.ControlKnobHigh_11.Value;
        end

        % Value changed function: ControlKnobLow_12
        function ControlKnobLow_12ValueChanged(app, event)
            app.ControlKnob_12.Limits(1) = app.ControlKnobLow_12.Value;
        end

        % Value changed function: ControlKnobHigh_12
        function ControlKnobHigh_12ValueChanged(app, event)
            app.ControlKnob_12.Limits(2) = app.ControlKnobHigh_12.Value;
        end

        % Value changed function: ControlKnobLow_13
        function ControlKnobLow_13ValueChanged(app, event)
            app.ControlKnob_13.Limits(1) = app.ControlKnobLow_13.Value;
        end

        % Value changed function: ControlKnobHigh_13
        function ControlKnobHigh_13ValueChanged(app, event)
            app.ControlKnob_13.Limits(2) = app.ControlKnobHigh_13.Value;
        end

        % Value changed function: ControlKnobLow_14
        function ControlKnobLow_14ValueChanged(app, event)
            app.ControlKnob_14.Limits(1) = app.ControlKnobLow_14.Value;
        end

        % Value changed function: ControlKnobHigh_14
        function ControlKnobHigh_14ValueChanged(app, event)
            app.ControlKnob_14.Limits(2) = app.ControlKnobHigh_14.Value;
        end

        % Value changed function: ControlKnobLow_15
        function ControlKnobLow_15ValueChanged(app, event)
            app.ControlKnob_15.Limits(1) = app.ControlKnobLow_15.Value;
        end

        % Value changed function: ControlKnobHigh_15
        function ControlKnobHigh_15ValueChanged(app, event)
            app.ControlKnob_15.Limits(2) = app.ControlKnobHigh_15.Value;
        end

        % Value changed function: ControlKnobLow_16
        function ControlKnobLow_16ValueChanged(app, event)
            app.ControlKnob_16.Limits(1) = app.ControlKnobLow_16.Value;
        end

        % Value changed function: ControlKnobHigh_16
        function ControlKnobHigh_16ValueChanged(app, event)
            app.ControlKnob_16.Limits(2) = app.ControlKnobHigh_16.Value;
        end

        % Value changed function: ControlKnobLow_17
        function ControlKnobLow_17ValueChanged(app, event)
            app.ControlKnob_17.Limits(1) = app.ControlKnobLow_17.Value;
        end

        % Value changed function: ControlKnobHigh_17
        function ControlKnobHigh_17ValueChanged(app, event)
            app.ControlKnob_17.Limits(2) = app.ControlKnobHigh_17.Value;
        end

        % Value changed function: ControlKnobLow_18
        function ControlKnobLow_18ValueChanged(app, event)
            app.ControlKnob_18.Limits(1) = app.ControlKnobLow_18.Value;
        end

        % Value changed function: ControlKnobHigh_18
        function ControlKnobHigh_18ValueChanged(app, event)
            app.ControlKnob_18.Limits(2) = app.ControlKnobHigh_18.Value;
        end

        % Value changed function: ControlKnobLow_19
        function ControlKnobLow_19ValueChanged(app, event)
            app.ControlKnob_19.Limits(1) = app.ControlKnobLow_19.Value;
        end

        % Value changed function: ControlKnobHigh_19
        function ControlKnobHigh_19ValueChanged(app, event)
            app.ControlKnob_19.Limits(2) = app.ControlKnobHigh_19.Value;
        end

        % Value changed function: ControlKnobLow_20
        function ControlKnobLow_20ValueChanged(app, event)
            app.ControlKnob_20.Limits(1) = app.ControlKnobLow_20.Value;
        end

        % Value changed function: ControlKnobHigh_20
        function ControlKnobHigh_20ValueChanged(app, event)
            app.ControlKnob_20.Limits(2) = app.ControlKnobHigh_20.Value;
        end

        % Value changed function: ControlKnobLow_21
        function ControlKnobLow_21ValueChanged(app, event)
            app.ControlKnob_21.Limits(1) = app.ControlKnobLow_21.Value;
        end

        % Value changed function: ControlKnobHigh_21
        function ControlKnobHigh_21ValueChanged(app, event)
            app.ControlKnob_21.Limits(2) = app.ControlKnobHigh_21.Value;
        end

        % Value changed function: ControlKnobLow_22
        function ControlKnobLow_22ValueChanged(app, event)
            app.ControlKnob_22.Limits(1) = app.ControlKnobLow_22.Value;
        end

        % Value changed function: ControlKnobHigh_22
        function ControlKnobHigh_22ValueChanged(app, event)
            app.ControlKnob_22.Limits(2) = app.ControlKnobHigh_22.Value;
        end

        % Value changed function: ControlKnobLow_23
        function ControlKnobLow_23ValueChanged(app, event)
            app.ControlKnob_23.Limits(1) = app.ControlKnobLow_23.Value;
        end

        % Value changed function: ControlKnobHigh_23
        function ControlKnobHigh_23ValueChanged(app, event)
            app.ControlKnob_23.Limits(2) = app.ControlKnobHigh_23.Value;
        end

        % Value changed function: ControlKnobLow_24
        function ControlKnobLow_24ValueChanged(app, event)
            app.ControlKnob_24.Limits(1) = app.ControlKnobLow_24.Value;
        end

        % Value changed function: ControlKnobHigh_24
        function ControlKnobHigh_24ValueChanged(app, event)
            app.ControlKnob_24.Limits(2) = app.ControlKnobHigh_24.Value;
        end

        % Value changed function: ControlKnobLow_25
        function ControlKnobLow_25ValueChanged(app, event)
            app.ControlKnob_25.Limits(1) = app.ControlKnobLow_25.Value;
        end

        % Value changed function: ControlKnobHigh_25
        function ControlKnobHigh_25ValueChanged(app, event)
            app.ControlKnob_25.Limits(2) = app.ControlKnobHigh_25.Value;
        end

        % Value changed function: ControlKnobLow_26
        function ControlKnobLow_26ValueChanged(app, event)
            app.ControlKnob_26.Limits(1) = app.ControlKnobLow_26.Value;
        end

        % Value changed function: ControlKnobHigh_26
        function ControlKnobHigh_26ValueChanged(app, event)
            app.ControlKnob_26.Limits(2) = app.ControlKnobHigh_26.Value;
        end

        % Value changed function: ControlKnobLow_27
        function ControlKnobLow_27ValueChanged(app, event)
            app.ControlKnob_27.Limits(1) = app.ControlKnobLow_27.Value;
        end

        % Value changed function: ControlKnobHigh_27
        function ControlKnobHigh_27ValueChanged(app, event)
            app.ControlKnob_27.Limits(2) = app.ControlKnobHigh_27.Value;
        end

        % Value changed function: ControlKnobLow_28
        function ControlKnobLow_28ValueChanged(app, event)
            app.ControlKnob_28.Limits(1) = app.ControlKnobLow_28.Value;
        end

        % Value changed function: ControlKnobHigh_28
        function ControlKnobHigh_28ValueChanged(app, event)
            app.ControlKnob_28.Limits(2) = app.ControlKnobHigh_28.Value;
        end

        % Value changed function: ControlKnobLow_29
        function ControlKnobLow_29ValueChanged(app, event)
            app.ControlKnob_29.Limits(1) = app.ControlKnobLow_29.Value;
        end

        % Value changed function: ControlKnobHigh_29
        function ControlKnobHigh_29ValueChanged(app, event)
            app.ControlKnob_29.Limits(2) = app.ControlKnobHigh_29.Value;
        end

        % Value changed function: ControlKnobLow_30
        function ControlKnobLow_30ValueChanged(app, event)
            app.ControlKnob_30.Limits(1) = app.ControlKnobLow_30.Value;
        end

        % Value changed function: ControlKnobHigh_30
        function ControlKnobHigh_30ValueChanged(app, event)
            app.ControlKnob_30.Limits(2) = app.ControlKnobHigh_30.Value;
        end

        % Value changed function: PlantKnobLow_13
        function PlantKnobLow_13ValueChanged(app, event)
            app.PlantKnob_13.Limits(1) = app.PlantKnobLow_13.Value;
        end

        % Value changed function: PlantKnobHigh_13
        function PlantKnobHigh_13ValueChanged(app, event)
            app.PlantKnob_13.Limits(2) = app.PlantKnobHigh_13.Value;
        end

        % Value changed function: PlantKnobLow_14
        function PlantKnobLow_14ValueChanged(app, event)
            app.PlantKnob_14.Limits(1) = app.PlantKnobLow_14.Value;
        end

        % Value changed function: PlantKnobHigh_14
        function PlantKnobHigh_14ValueChanged(app, event)
            app.PlantKnob_14.Limits(2) = app.PlantKnobHigh_14.Value;
        end

        % Value changed function: PlantKnobLow_15
        function PlantKnobLow_15ValueChanged(app, event)
            app.PlantKnob_15.Limits(1) = app.PlantKnobLow_15.Value;
        end

        % Value changed function: PlantKnobHigh_15
        function PlantKnobHigh_15ValueChanged(app, event)
            app.PlantKnob_15.Limits(2) = app.PlantKnobHigh_15.Value;
        end

        % Value changed function: PlantKnobLow_16
        function PlantKnobLow_16ValueChanged(app, event)
            app.PlantKnob_16.Limits(1) = app.PlantKnobLow_16.Value;
        end

        % Value changed function: PlantKnobHigh_16
        function PlantKnobHigh_16ValueChanged(app, event)
            app.PlantKnob_16.Limits(2) = app.PlantKnobHigh_16.Value;
        end

        % Button pushed function: SymbolicDisplayButton
        function SymbolicDisplayButtonPushed(app, event)
            % Define Symbolic Parameters
            PlantParams = fieldnames(app.PlantParameters);
            ControlParams = fieldnames(app.ControlParameters);
            for i = 1 : length(PlantParams)
               eval([PlantParams{i}, ' = sym(''', PlantParams{i}, ''', ''positive'');']); 
               eval(['Parameters.', PlantParams{i}, ' = ', PlantParams{i}, ';']);
            end
            for i = 1 : length(ControlParams)
               eval([ControlParams{i}, ' = sym(''', ControlParams{i}, ''', ''positive'');']); 
               eval(['Parameters.', ControlParams{i}, ' = ', ControlParams{i}, ';']);
            end
            % Define Symbolic Variables
            Variables = app.Names_Deterministic;
            for i = 1 : length(Variables)
               eval([Variables{i}, ' = sym(''', Variables{i}, ''', ''positive'');']); 
            end
            % Compute RHS of ODEs
            SymbolicDisplay(Parameters, Variables, app.StoichiometryMatrix, app.PropensityFunction, app.NetworkDropDown.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.NetworkDropDownLabel = uilabel(app.UIFigure);
            app.NetworkDropDownLabel.Text = 'Network';
            app.NetworkDropDown = uidropdown(app.UIFigure);
            app.NetworkDropDown.Items = {};
            app.NetworkDropDown.ValueChangedFcn = createCallbackFcn(app, @NetworkDropDownValueChanged, true);
            app.NetworkDropDown.Value = {};
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Control Panel';
            app.TabGroup3 = uitabgroup(app.ControlPanel);
            app.Panel1Tab_2 = uitab(app.TabGroup3);
            app.Panel1Tab_2.Title = 'Panel 1';
            app.ControlKnobPanel_1 = uipanel(app.Panel1Tab_2);
            app.ControlKnob_1 = uiknob(app.ControlKnobPanel_1, 'continuous');
            app.ControlKnob_1.Value = 100;
            app.ControlKnobLabel_1 = uilabel(app.ControlKnobPanel_1);
            app.ControlKnobLabel_1.Text = 'Knob';
            app.ControlKnobLow_1 = uieditfield(app.ControlKnobPanel_1, 'numeric');
            app.ControlKnobLow_1.ValueChangedFcn = createCallbackFcn(app, @ControlKnobLow_1ValueChanged, true);
            app.ControlKnobHigh_1 = uieditfield(app.ControlKnobPanel_1, 'numeric');
            app.ControlKnobHigh_1.ValueChangedFcn = createCallbackFcn(app, @ControlKnobHigh_1ValueChanged, true);
            app.ControlKnobHigh_1.Value = 100;
            app.ControlKnobPanel_2 = uipanel(app.Panel1Tab_2);
            app.ControlKnob_2 = uiknob(app.ControlKnobPanel_2, 'continuous');
            app.ControlKnob_2.Limits = [0 10];
            app.ControlKnob_2.Value = 5;
            app.ControlKnobLabel_2 = uilabel(app.ControlKnobPanel_2);
            app.ControlKnobLabel_2.Text = 'Knob';
            app.ControlKnobLow_2 = uieditfield(app.ControlKnobPanel_2, 'numeric');
            app.ControlKnobLow_2.ValueChangedFcn = createCallbackFcn(app, @ControlKnobLow_2ValueChanged, true);
            app.ControlKnobHigh_2 = uieditfield(app.ControlKnobPanel_2, 'numeric');
            app.ControlKnobHigh_2.ValueChangedFcn = createCallbackFcn(app, @ControlKnobHigh_2ValueChanged, true);
            app.ControlKnobHigh_2.Value = 10;
            app.ControlKnobPanel_3 = uipanel(app.Panel1Tab_2);
            app.ControlKnob_3 = uiknob(app.ControlKnobPanel_3, 'continuous');
            app.ControlKnob_3.Limits = [0 10];
            app.ControlKnob_3.Value = 5;
            app.ControlKnobLabel_3 = uilabel(app.ControlKnobPanel_3);
            app.ControlKnobLabel_3.Text = 'Knob';
            app.ControlKnobLow_3 = uieditfield(app.ControlKnobPanel_3, 'numeric');
            app.ControlKnobLow_3.ValueChangedFcn = createCallbackFcn(app, @ControlKnobLow_3ValueChanged, true);
            app.ControlKnobHigh_3 = uieditfield(app.ControlKnobPanel_3, 'numeric');
            app.ControlKnobHigh_3.ValueChangedFcn = createCallbackFcn(app, @ControlKnobHigh_3ValueChanged, true);
            app.ControlKnobHigh_3.Value = 10;
            app.ControlKnobPanel_4 = uipanel(app.Panel1Tab_2);
            app.ControlKnob_4 = uiknob(app.ControlKnobPanel_4, 'continuous');
            app.ControlKnob_4.Limits = [0 1000];
            app.ControlKnob_4.Value = 1000;
            app.ControlKnobLabel_4 = uilabel(app.ControlKnobPanel_4);
            app.ControlKnobLabel_4.Text = 'Knob';
            app.ControlKnobLow_4 = uieditfield(app.ControlKnobPanel_4, 'numeric');
            app.ControlKnobLow_4.ValueChangedFcn = createCallbackFcn(app, @ControlKnobLow_4ValueChanged, true);
            app.ControlKnobHigh_4 = uieditfield(app.ControlKnobPanel_4, 'numeric');
            app.ControlKnobHigh_4.ValueChangedFcn = createCallbackFcn(app, @ControlKnobHigh_4ValueChanged, true);
            app.ControlKnobHigh_4.Value = 1000;
            app.ControlKnobPanel_7 = uipanel(app.Panel1Tab_2);
            app.ControlKnob_7 = uiknob(app.ControlKnobPanel_7, 'continuous');
            app.ControlKnob_7.Limits = [0 10];
            app.ControlKnob_7.Value = 5;
            app.ControlKnobLabel_7 = uilabel(app.ControlKnobPanel_7);
            app.ControlKnobLabel_7.Text = 'Knob';
            app.ControlKnobLow_7 = uieditfield(app.ControlKnobPanel_7, 'numeric');
            app.ControlKnobLow_7.ValueChangedFcn = createCallbackFcn(app, @ControlKnobLow_7ValueChanged, true);
            app.ControlKnobHigh_7 = uieditfield(app.ControlKnobPanel_7, 'numeric');
            app.ControlKnobHigh_7.ValueChangedFcn = createCallbackFcn(app, @ControlKnobHigh_7ValueChanged, true);
            app.ControlKnobHigh_7.Value = 10;
            app.ControlKnobPanel_8 = uipanel(app.Panel1Tab_2);
            app.ControlKnob_8 = uiknob(app.ControlKnobPanel_8, 'continuous');
            app.ControlKnob_8.Limits = [0 1000];
            app.ControlKnob_8.Value = 1000;
            app.ControlKnobLabel_8 = uilabel(app.ControlKnobPanel_8);
            app.ControlKnobLabel_8.Text = 'Knob';
            app.ControlKnobLow_8 = uieditfield(app.ControlKnobPanel_8, 'numeric');
            app.ControlKnobLow_8.ValueChangedFcn = createCallbackFcn(app, @ControlKnobLow_8ValueChanged, true);
            app.ControlKnobHigh_8 = uieditfield(app.ControlKnobPanel_8, 'numeric');
            app.ControlKnobHigh_8.ValueChangedFcn = createCallbackFcn(app, @ControlKnobHigh_8ValueChanged, true);
            app.ControlKnobHigh_8.Value = 1000;
            app.ControlKnobPanel_10 = uipanel(app.Panel1Tab_2);
            app.ControlKnob_10 = uiknob(app.ControlKnobPanel_10, 'continuous');
            app.ControlKnob_10.Limits = [0 10];
            app.ControlKnob_10.Value = 5;
            app.ControlKnobLabel_10 = uilabel(app.ControlKnobPanel_10);
            app.ControlKnobLabel_10.Text = 'Knob';
            app.ControlKnobLow_10 = uieditfield(app.ControlKnobPanel_10, 'numeric');
            app.ControlKnobLow_10.ValueChangedFcn = createCallbackFcn(app, @ControlKnobLow_10ValueChanged, true);
            app.ControlKnobHigh_10 = uieditfield(app.ControlKnobPanel_10, 'numeric');
            app.ControlKnobHigh_10.ValueChangedFcn = createCallbackFcn(app, @ControlKnobHigh_10ValueChanged, true);
            app.ControlKnobHigh_10.Value = 10;
            app.ControlKnobPanel_5 = uipanel(app.Panel1Tab_2);
            app.ControlKnob_5 = uiknob(app.ControlKnobPanel_5, 'continuous');
            app.ControlKnob_5.Value = 100;
            app.ControlKnobLabel_5 = uilabel(app.ControlKnobPanel_5);
            app.ControlKnobLabel_5.Text = 'Knob';
            app.ControlKnobLow_5 = uieditfield(app.ControlKnobPanel_5, 'numeric');
            app.ControlKnobLow_5.ValueChangedFcn = createCallbackFcn(app, @ControlKnobLow_5ValueChanged, true);
            app.ControlKnobHigh_5 = uieditfield(app.ControlKnobPanel_5, 'numeric');
            app.ControlKnobHigh_5.ValueChangedFcn = createCallbackFcn(app, @ControlKnobHigh_5ValueChanged, true);
            app.ControlKnobHigh_5.Value = 100;
            app.ControlKnobPanel_6 = uipanel(app.Panel1Tab_2);
            app.ControlKnob_6 = uiknob(app.ControlKnobPanel_6, 'continuous');
            app.ControlKnob_6.Limits = [0 10];
            app.ControlKnob_6.Value = 5;
            app.ControlKnobLabel_6 = uilabel(app.ControlKnobPanel_6);
            app.ControlKnobLabel_6.Text = 'Knob';
            app.ControlKnobLow_6 = uieditfield(app.ControlKnobPanel_6, 'numeric');
            app.ControlKnobLow_6.ValueChangedFcn = createCallbackFcn(app, @ControlKnobLow_6ValueChanged, true);
            app.ControlKnobHigh_6 = uieditfield(app.ControlKnobPanel_6, 'numeric');
            app.ControlKnobHigh_6.ValueChangedFcn = createCallbackFcn(app, @ControlKnobHigh_6ValueChanged, true);
            app.ControlKnobHigh_6.Value = 10;
            app.ControlKnobPanel_9 = uipanel(app.Panel1Tab_2);
            app.ControlKnob_9 = uiknob(app.ControlKnobPanel_9, 'continuous');
            app.ControlKnob_9.Limits = [0 10];
            app.ControlKnob_9.Value = 5;
            app.ControlKnobLabel_9 = uilabel(app.ControlKnobPanel_9);
            app.ControlKnobLabel_9.Text = 'Knob';
            app.ControlKnobLow_9 = uieditfield(app.ControlKnobPanel_9, 'numeric');
            app.ControlKnobLow_9.ValueChangedFcn = createCallbackFcn(app, @ControlKnobLow_9ValueChanged, true);
            app.ControlKnobHigh_9 = uieditfield(app.ControlKnobPanel_9, 'numeric');
            app.ControlKnobHigh_9.ValueChangedFcn = createCallbackFcn(app, @ControlKnobHigh_9ValueChanged, true);
            app.ControlKnobHigh_9.Value = 10;
            app.Panel2Tab_2 = uitab(app.TabGroup3);
            app.Panel2Tab_2.Title = 'Panel 2';
            app.ControlKnobPanel_11 = uipanel(app.Panel2Tab_2);
            app.ControlKnob_11 = uiknob(app.ControlKnobPanel_11, 'continuous');
            app.ControlKnob_11.Value = 100;
            app.ControlKnobLabel_11 = uilabel(app.ControlKnobPanel_11);
            app.ControlKnobLabel_11.Text = 'Knob';
            app.ControlKnobLow_11 = uieditfield(app.ControlKnobPanel_11, 'numeric');
            app.ControlKnobLow_11.ValueChangedFcn = createCallbackFcn(app, @ControlKnobLow_11ValueChanged, true);
            app.ControlKnobHigh_11 = uieditfield(app.ControlKnobPanel_11, 'numeric');
            app.ControlKnobHigh_11.ValueChangedFcn = createCallbackFcn(app, @ControlKnobHigh_11ValueChanged, true);
            app.ControlKnobHigh_11.Value = 100;
            app.ControlKnobPanel_12 = uipanel(app.Panel2Tab_2);
            app.ControlKnob_12 = uiknob(app.ControlKnobPanel_12, 'continuous');
            app.ControlKnob_12.Limits = [0 10];
            app.ControlKnob_12.Value = 5;
            app.ControlKnobLabel_12 = uilabel(app.ControlKnobPanel_12);
            app.ControlKnobLabel_12.Text = 'Knob';
            app.ControlKnobLow_12 = uieditfield(app.ControlKnobPanel_12, 'numeric');
            app.ControlKnobLow_12.ValueChangedFcn = createCallbackFcn(app, @ControlKnobLow_12ValueChanged, true);
            app.ControlKnobHigh_12 = uieditfield(app.ControlKnobPanel_12, 'numeric');
            app.ControlKnobHigh_12.ValueChangedFcn = createCallbackFcn(app, @ControlKnobHigh_12ValueChanged, true);
            app.ControlKnobHigh_12.Value = 10;
            app.ControlKnobPanel_13 = uipanel(app.Panel2Tab_2);
            app.ControlKnob_13 = uiknob(app.ControlKnobPanel_13, 'continuous');
            app.ControlKnob_13.Limits = [0 10];
            app.ControlKnob_13.Value = 5;
            app.ControlKnobLabel_13 = uilabel(app.ControlKnobPanel_13);
            app.ControlKnobLabel_13.Text = 'Knob';
            app.ControlKnobLow_13 = uieditfield(app.ControlKnobPanel_13, 'numeric');
            app.ControlKnobLow_13.ValueChangedFcn = createCallbackFcn(app, @ControlKnobLow_13ValueChanged, true);
            app.ControlKnobHigh_13 = uieditfield(app.ControlKnobPanel_13, 'numeric');
            app.ControlKnobHigh_13.ValueChangedFcn = createCallbackFcn(app, @ControlKnobHigh_13ValueChanged, true);
            app.ControlKnobHigh_13.Value = 10;
            app.ControlKnobPanel_14 = uipanel(app.Panel2Tab_2);
            app.ControlKnob_14 = uiknob(app.ControlKnobPanel_14, 'continuous');
            app.ControlKnob_14.Limits = [0 1000];
            app.ControlKnob_14.Value = 1000;
            app.ControlKnobLabel_14 = uilabel(app.ControlKnobPanel_14);
            app.ControlKnobLabel_14.Text = 'Knob';
            app.ControlKnobLow_14 = uieditfield(app.ControlKnobPanel_14, 'numeric');
            app.ControlKnobLow_14.ValueChangedFcn = createCallbackFcn(app, @ControlKnobLow_14ValueChanged, true);
            app.ControlKnobHigh_14 = uieditfield(app.ControlKnobPanel_14, 'numeric');
            app.ControlKnobHigh_14.ValueChangedFcn = createCallbackFcn(app, @ControlKnobHigh_14ValueChanged, true);
            app.ControlKnobHigh_14.Value = 1000;
            app.ControlKnobPanel_15 = uipanel(app.Panel2Tab_2);
            app.ControlKnob_15 = uiknob(app.ControlKnobPanel_15, 'continuous');
            app.ControlKnob_15.Limits = [0 10];
            app.ControlKnob_15.Value = 5;
            app.ControlKnobLabel_15 = uilabel(app.ControlKnobPanel_15);
            app.ControlKnobLabel_15.Text = 'Knob';
            app.ControlKnobLow_15 = uieditfield(app.ControlKnobPanel_15, 'numeric');
            app.ControlKnobLow_15.ValueChangedFcn = createCallbackFcn(app, @ControlKnobLow_15ValueChanged, true);
            app.ControlKnobHigh_15 = uieditfield(app.ControlKnobPanel_15, 'numeric');
            app.ControlKnobHigh_15.ValueChangedFcn = createCallbackFcn(app, @ControlKnobHigh_15ValueChanged, true);
            app.ControlKnobHigh_15.Value = 10;
            app.ControlKnobPanel_16 = uipanel(app.Panel2Tab_2);
            app.ControlKnob_16 = uiknob(app.ControlKnobPanel_16, 'continuous');
            app.ControlKnob_16.Limits = [0 1000];
            app.ControlKnob_16.Value = 1000;
            app.ControlKnobLabel_16 = uilabel(app.ControlKnobPanel_16);
            app.ControlKnobLabel_16.Text = 'Knob';
            app.ControlKnobLow_16 = uieditfield(app.ControlKnobPanel_16, 'numeric');
            app.ControlKnobLow_16.ValueChangedFcn = createCallbackFcn(app, @ControlKnobLow_16ValueChanged, true);
            app.ControlKnobHigh_16 = uieditfield(app.ControlKnobPanel_16, 'numeric');
            app.ControlKnobHigh_16.ValueChangedFcn = createCallbackFcn(app, @ControlKnobHigh_16ValueChanged, true);
            app.ControlKnobHigh_16.Value = 1000;
            app.ControlKnobPanel_17 = uipanel(app.Panel2Tab_2);
            app.ControlKnob_17 = uiknob(app.ControlKnobPanel_17, 'continuous');
            app.ControlKnob_17.Limits = [0 10];
            app.ControlKnob_17.Value = 5;
            app.ControlKnobLabel_17 = uilabel(app.ControlKnobPanel_17);
            app.ControlKnobLabel_17.Text = 'Knob';
            app.ControlKnobLow_17 = uieditfield(app.ControlKnobPanel_17, 'numeric');
            app.ControlKnobLow_17.ValueChangedFcn = createCallbackFcn(app, @ControlKnobLow_17ValueChanged, true);
            app.ControlKnobHigh_17 = uieditfield(app.ControlKnobPanel_17, 'numeric');
            app.ControlKnobHigh_17.ValueChangedFcn = createCallbackFcn(app, @ControlKnobHigh_17ValueChanged, true);
            app.ControlKnobHigh_17.Value = 10;
            app.ControlKnobPanel_18 = uipanel(app.Panel2Tab_2);
            app.ControlKnob_18 = uiknob(app.ControlKnobPanel_18, 'continuous');
            app.ControlKnob_18.Value = 100;
            app.ControlKnobLabel_18 = uilabel(app.ControlKnobPanel_18);
            app.ControlKnobLabel_18.Text = 'Knob';
            app.ControlKnobLow_18 = uieditfield(app.ControlKnobPanel_18, 'numeric');
            app.ControlKnobLow_18.ValueChangedFcn = createCallbackFcn(app, @ControlKnobLow_18ValueChanged, true);
            app.ControlKnobHigh_18 = uieditfield(app.ControlKnobPanel_18, 'numeric');
            app.ControlKnobHigh_18.ValueChangedFcn = createCallbackFcn(app, @ControlKnobHigh_18ValueChanged, true);
            app.ControlKnobHigh_18.Value = 100;
            app.ControlKnobPanel_19 = uipanel(app.Panel2Tab_2);
            app.ControlKnob_19 = uiknob(app.ControlKnobPanel_19, 'continuous');
            app.ControlKnob_19.Limits = [0 10];
            app.ControlKnob_19.Value = 5;
            app.ControlKnobLabel_19 = uilabel(app.ControlKnobPanel_19);
            app.ControlKnobLabel_19.Text = 'Knob';
            app.ControlKnobLow_19 = uieditfield(app.ControlKnobPanel_19, 'numeric');
            app.ControlKnobLow_19.ValueChangedFcn = createCallbackFcn(app, @ControlKnobLow_19ValueChanged, true);
            app.ControlKnobHigh_19 = uieditfield(app.ControlKnobPanel_19, 'numeric');
            app.ControlKnobHigh_19.ValueChangedFcn = createCallbackFcn(app, @ControlKnobHigh_19ValueChanged, true);
            app.ControlKnobHigh_19.Value = 10;
            app.ControlKnobPanel_20 = uipanel(app.Panel2Tab_2);
            app.ControlKnob_20 = uiknob(app.ControlKnobPanel_20, 'continuous');
            app.ControlKnob_20.Limits = [0 10];
            app.ControlKnob_20.Value = 5;
            app.ControlKnobLabel_20 = uilabel(app.ControlKnobPanel_20);
            app.ControlKnobLabel_20.Text = 'Knob';
            app.ControlKnobLow_20 = uieditfield(app.ControlKnobPanel_20, 'numeric');
            app.ControlKnobLow_20.ValueChangedFcn = createCallbackFcn(app, @ControlKnobLow_20ValueChanged, true);
            app.ControlKnobHigh_20 = uieditfield(app.ControlKnobPanel_20, 'numeric');
            app.ControlKnobHigh_20.ValueChangedFcn = createCallbackFcn(app, @ControlKnobHigh_20ValueChanged, true);
            app.ControlKnobHigh_20.Value = 10;
            app.Panel3Tab = uitab(app.TabGroup3);
            app.Panel3Tab.Title = 'Panel 3';
            app.ControlKnobPanel_21 = uipanel(app.Panel3Tab);
            app.ControlKnob_21 = uiknob(app.ControlKnobPanel_21, 'continuous');
            app.ControlKnob_21.Value = 100;
            app.ControlKnobLabel_21 = uilabel(app.ControlKnobPanel_21);
            app.ControlKnobLabel_21.Text = 'Knob';
            app.ControlKnobLow_21 = uieditfield(app.ControlKnobPanel_21, 'numeric');
            app.ControlKnobLow_21.ValueChangedFcn = createCallbackFcn(app, @ControlKnobLow_21ValueChanged, true);
            app.ControlKnobHigh_21 = uieditfield(app.ControlKnobPanel_21, 'numeric');
            app.ControlKnobHigh_21.ValueChangedFcn = createCallbackFcn(app, @ControlKnobHigh_21ValueChanged, true);
            app.ControlKnobHigh_21.Value = 100;
            app.ControlKnobPanel_22 = uipanel(app.Panel3Tab);
            app.ControlKnob_22 = uiknob(app.ControlKnobPanel_22, 'continuous');
            app.ControlKnob_22.Limits = [0 10];
            app.ControlKnob_22.Value = 5;
            app.ControlKnobLabel_22 = uilabel(app.ControlKnobPanel_22);
            app.ControlKnobLabel_22.Text = 'Knob';
            app.ControlKnobLow_22 = uieditfield(app.ControlKnobPanel_22, 'numeric');
            app.ControlKnobLow_22.ValueChangedFcn = createCallbackFcn(app, @ControlKnobLow_22ValueChanged, true);
            app.ControlKnobHigh_22 = uieditfield(app.ControlKnobPanel_22, 'numeric');
            app.ControlKnobHigh_22.ValueChangedFcn = createCallbackFcn(app, @ControlKnobHigh_22ValueChanged, true);
            app.ControlKnobHigh_22.Value = 10;
            app.ControlKnobPanel_23 = uipanel(app.Panel3Tab);
            app.ControlKnob_23 = uiknob(app.ControlKnobPanel_23, 'continuous');
            app.ControlKnob_23.Limits = [0 10];
            app.ControlKnob_23.Value = 5;
            app.ControlKnobLabel_23 = uilabel(app.ControlKnobPanel_23);
            app.ControlKnobLabel_23.Text = 'Knob';
            app.ControlKnobLow_23 = uieditfield(app.ControlKnobPanel_23, 'numeric');
            app.ControlKnobLow_23.ValueChangedFcn = createCallbackFcn(app, @ControlKnobLow_23ValueChanged, true);
            app.ControlKnobHigh_23 = uieditfield(app.ControlKnobPanel_23, 'numeric');
            app.ControlKnobHigh_23.ValueChangedFcn = createCallbackFcn(app, @ControlKnobHigh_23ValueChanged, true);
            app.ControlKnobHigh_23.Value = 10;
            app.ControlKnobPanel_24 = uipanel(app.Panel3Tab);
            app.ControlKnob_24 = uiknob(app.ControlKnobPanel_24, 'continuous');
            app.ControlKnob_24.Limits = [0 1000];
            app.ControlKnob_24.Value = 1000;
            app.ControlKnobLabel_24 = uilabel(app.ControlKnobPanel_24);
            app.ControlKnobLabel_24.Text = 'Knob';
            app.ControlKnobLow_24 = uieditfield(app.ControlKnobPanel_24, 'numeric');
            app.ControlKnobLow_24.ValueChangedFcn = createCallbackFcn(app, @ControlKnobLow_24ValueChanged, true);
            app.ControlKnobHigh_24 = uieditfield(app.ControlKnobPanel_24, 'numeric');
            app.ControlKnobHigh_24.ValueChangedFcn = createCallbackFcn(app, @ControlKnobHigh_24ValueChanged, true);
            app.ControlKnobHigh_24.Value = 1000;
            app.ControlKnobPanel_25 = uipanel(app.Panel3Tab);
            app.ControlKnob_25 = uiknob(app.ControlKnobPanel_25, 'continuous');
            app.ControlKnob_25.Limits = [0 10];
            app.ControlKnob_25.Value = 5;
            app.ControlKnobLabel_25 = uilabel(app.ControlKnobPanel_25);
            app.ControlKnobLabel_25.Text = 'Knob';
            app.ControlKnobLow_25 = uieditfield(app.ControlKnobPanel_25, 'numeric');
            app.ControlKnobLow_25.ValueChangedFcn = createCallbackFcn(app, @ControlKnobLow_25ValueChanged, true);
            app.ControlKnobHigh_25 = uieditfield(app.ControlKnobPanel_25, 'numeric');
            app.ControlKnobHigh_25.ValueChangedFcn = createCallbackFcn(app, @ControlKnobHigh_25ValueChanged, true);
            app.ControlKnobHigh_25.Value = 10;
            app.ControlKnobPanel_26 = uipanel(app.Panel3Tab);
            app.ControlKnob_26 = uiknob(app.ControlKnobPanel_26, 'continuous');
            app.ControlKnob_26.Limits = [0 1000];
            app.ControlKnob_26.Value = 1000;
            app.ControlKnobLabel_26 = uilabel(app.ControlKnobPanel_26);
            app.ControlKnobLabel_26.Text = 'Knob';
            app.ControlKnobLow_26 = uieditfield(app.ControlKnobPanel_26, 'numeric');
            app.ControlKnobLow_26.ValueChangedFcn = createCallbackFcn(app, @ControlKnobLow_26ValueChanged, true);
            app.ControlKnobHigh_26 = uieditfield(app.ControlKnobPanel_26, 'numeric');
            app.ControlKnobHigh_26.ValueChangedFcn = createCallbackFcn(app, @ControlKnobHigh_26ValueChanged, true);
            app.ControlKnobHigh_26.Value = 1000;
            app.ControlKnobPanel_27 = uipanel(app.Panel3Tab);
            app.ControlKnob_27 = uiknob(app.ControlKnobPanel_27, 'continuous');
            app.ControlKnob_27.Limits = [0 10];
            app.ControlKnob_27.Value = 5;
            app.ControlKnobLabel_27 = uilabel(app.ControlKnobPanel_27);
            app.ControlKnobLabel_27.Text = 'Knob';
            app.ControlKnobLow_27 = uieditfield(app.ControlKnobPanel_27, 'numeric');
            app.ControlKnobLow_27.ValueChangedFcn = createCallbackFcn(app, @ControlKnobLow_27ValueChanged, true);
            app.ControlKnobHigh_27 = uieditfield(app.ControlKnobPanel_27, 'numeric');
            app.ControlKnobHigh_27.ValueChangedFcn = createCallbackFcn(app, @ControlKnobHigh_27ValueChanged, true);
            app.ControlKnobHigh_27.Value = 10;
            app.ControlKnobPanel_28 = uipanel(app.Panel3Tab);
            app.ControlKnob_28 = uiknob(app.ControlKnobPanel_28, 'continuous');
            app.ControlKnob_28.Value = 100;
            app.ControlKnobLabel_28 = uilabel(app.ControlKnobPanel_28);
            app.ControlKnobLabel_28.Text = 'Knob';
            app.ControlKnobLow_28 = uieditfield(app.ControlKnobPanel_28, 'numeric');
            app.ControlKnobLow_28.ValueChangedFcn = createCallbackFcn(app, @ControlKnobLow_28ValueChanged, true);
            app.ControlKnobHigh_28 = uieditfield(app.ControlKnobPanel_28, 'numeric');
            app.ControlKnobHigh_28.ValueChangedFcn = createCallbackFcn(app, @ControlKnobHigh_28ValueChanged, true);
            app.ControlKnobHigh_28.Value = 100;
            app.ControlKnobPanel_29 = uipanel(app.Panel3Tab);
            app.ControlKnob_29 = uiknob(app.ControlKnobPanel_29, 'continuous');
            app.ControlKnob_29.Limits = [0 10];
            app.ControlKnob_29.Value = 5;
            app.ControlKnobLabel_29 = uilabel(app.ControlKnobPanel_29);
            app.ControlKnobLabel_29.Text = 'Knob';
            app.ControlKnobLow_29 = uieditfield(app.ControlKnobPanel_29, 'numeric');
            app.ControlKnobLow_29.ValueChangedFcn = createCallbackFcn(app, @ControlKnobLow_29ValueChanged, true);
            app.ControlKnobHigh_29 = uieditfield(app.ControlKnobPanel_29, 'numeric');
            app.ControlKnobHigh_29.ValueChangedFcn = createCallbackFcn(app, @ControlKnobHigh_29ValueChanged, true);
            app.ControlKnobHigh_29.Value = 10;
            app.ControlKnobPanel_30 = uipanel(app.Panel3Tab);
            app.ControlKnob_30 = uiknob(app.ControlKnobPanel_30, 'continuous');
            app.ControlKnob_30.Limits = [0 10];
            app.ControlKnob_30.Value = 5;
            app.ControlKnobLabel_30 = uilabel(app.ControlKnobPanel_30);
            app.ControlKnobLabel_30.Text = 'Knob';
            app.ControlKnobLow_30 = uieditfield(app.ControlKnobPanel_30, 'numeric');
            app.ControlKnobLow_30.ValueChangedFcn = createCallbackFcn(app, @ControlKnobLow_30ValueChanged, true);
            app.ControlKnobHigh_30 = uieditfield(app.ControlKnobPanel_30, 'numeric');
            app.ControlKnobHigh_30.ValueChangedFcn = createCallbackFcn(app, @ControlKnobHigh_30ValueChanged, true);
            app.ControlKnobHigh_30.Value = 10;
            app.PlantPanel = uipanel(app.UIFigure);
            app.PlantPanel.Title = 'Plant Panel';
            app.TabGroup2 = uitabgroup(app.PlantPanel);
            app.Panel1Tab = uitab(app.TabGroup2);
            app.Panel1Tab.Title = 'Panel 1';
            app.PlantKnobPanel_1 = uipanel(app.Panel1Tab);
            app.PlantKnob_1 = uiknob(app.PlantKnobPanel_1, 'continuous');
            app.PlantKnob_1.Limits = [0 0.3];
            app.PlantKnob_1.Value = 0.1;
            app.PlantKnobLabel_1 = uilabel(app.PlantKnobPanel_1);
            app.PlantKnobLabel_1.Text = 'Knob';
            app.PlantKnobLow_1 = uieditfield(app.PlantKnobPanel_1, 'numeric');
            app.PlantKnobLow_1.ValueChangedFcn = createCallbackFcn(app, @PlantKnobLow_1ValueChanged, true);
            app.PlantKnobHigh_1 = uieditfield(app.PlantKnobPanel_1, 'numeric');
            app.PlantKnobHigh_1.ValueChangedFcn = createCallbackFcn(app, @PlantKnobHigh_1ValueChanged, true);
            app.PlantKnobHigh_1.Value = 0.3;
            app.PlantKnobPanel_2 = uipanel(app.Panel1Tab);
            app.PlantKnob_2 = uiknob(app.PlantKnobPanel_2, 'continuous');
            app.PlantKnob_2.Limits = [0 10];
            app.PlantKnob_2.Value = 5;
            app.PlantKnobLabel_2 = uilabel(app.PlantKnobPanel_2);
            app.PlantKnobLabel_2.Text = 'Knob';
            app.PlantKnobLow_2 = uieditfield(app.PlantKnobPanel_2, 'numeric');
            app.PlantKnobLow_2.ValueChangedFcn = createCallbackFcn(app, @PlantKnobLow_2ValueChanged, true);
            app.PlantKnobHigh_2 = uieditfield(app.PlantKnobPanel_2, 'numeric');
            app.PlantKnobHigh_2.ValueChangedFcn = createCallbackFcn(app, @PlantKnobHigh_2ValueChanged, true);
            app.PlantKnobHigh_2.Value = 10;
            app.PlantKnobPanel_3 = uipanel(app.Panel1Tab);
            app.PlantKnob_3 = uiknob(app.PlantKnobPanel_3, 'continuous');
            app.PlantKnob_3.Limits = [0 5];
            app.PlantKnob_3.Value = 2;
            app.PlantKnobLabel_3 = uilabel(app.PlantKnobPanel_3);
            app.PlantKnobLabel_3.Text = 'Knob';
            app.PlantKnobLow_3 = uieditfield(app.PlantKnobPanel_3, 'numeric');
            app.PlantKnobLow_3.ValueChangedFcn = createCallbackFcn(app, @PlantKnobLow_3ValueChanged, true);
            app.PlantKnobHigh_3 = uieditfield(app.PlantKnobPanel_3, 'numeric');
            app.PlantKnobHigh_3.ValueChangedFcn = createCallbackFcn(app, @PlantKnobHigh_3ValueChanged, true);
            app.PlantKnobHigh_3.Value = 5;
            app.PlantKnobPanel_4 = uipanel(app.Panel1Tab);
            app.PlantKnob_4 = uiknob(app.PlantKnobPanel_4, 'continuous');
            app.PlantKnob_4.Limits = [0 10];
            app.PlantKnob_4.Value = 5;
            app.PlantKnobLabel_4 = uilabel(app.PlantKnobPanel_4);
            app.PlantKnobLabel_4.Text = 'Knob';
            app.PlantKnobLow_4 = uieditfield(app.PlantKnobPanel_4, 'numeric');
            app.PlantKnobLow_4.ValueChangedFcn = createCallbackFcn(app, @PlantKnobLow_4ValueChanged, true);
            app.PlantKnobHigh_4 = uieditfield(app.PlantKnobPanel_4, 'numeric');
            app.PlantKnobHigh_4.ValueChangedFcn = createCallbackFcn(app, @PlantKnobHigh_4ValueChanged, true);
            app.PlantKnobHigh_4.Value = 10;
            app.Panel2Tab = uitab(app.TabGroup2);
            app.Panel2Tab.Title = 'Panel 2';
            app.PlantKnobPanel_5 = uipanel(app.Panel2Tab);
            app.PlantKnob_5 = uiknob(app.PlantKnobPanel_5, 'continuous');
            app.PlantKnob_5.Limits = [0 0.3];
            app.PlantKnob_5.Value = 0.1;
            app.PlantKnobLabel_5 = uilabel(app.PlantKnobPanel_5);
            app.PlantKnobLabel_5.Text = 'Knob';
            app.PlantKnobLow_5 = uieditfield(app.PlantKnobPanel_5, 'numeric');
            app.PlantKnobLow_5.ValueChangedFcn = createCallbackFcn(app, @PlantKnobLow_5ValueChanged, true);
            app.PlantKnobHigh_5 = uieditfield(app.PlantKnobPanel_5, 'numeric');
            app.PlantKnobHigh_5.ValueChangedFcn = createCallbackFcn(app, @PlantKnobHigh_5ValueChanged, true);
            app.PlantKnobHigh_5.Value = 0.3;
            app.PlantKnobPanel_6 = uipanel(app.Panel2Tab);
            app.PlantKnob_6 = uiknob(app.PlantKnobPanel_6, 'continuous');
            app.PlantKnob_6.Limits = [0 10];
            app.PlantKnob_6.Value = 5;
            app.PlantKnobLabel_6 = uilabel(app.PlantKnobPanel_6);
            app.PlantKnobLabel_6.Text = 'Knob';
            app.PlantKnobLow_6 = uieditfield(app.PlantKnobPanel_6, 'numeric');
            app.PlantKnobLow_6.ValueChangedFcn = createCallbackFcn(app, @PlantKnobLow_6ValueChanged, true);
            app.PlantKnobHigh_6 = uieditfield(app.PlantKnobPanel_6, 'numeric');
            app.PlantKnobHigh_6.ValueChangedFcn = createCallbackFcn(app, @PlantKnobHigh_6ValueChanged, true);
            app.PlantKnobHigh_6.Value = 10;
            app.PlantKnobPanel_7 = uipanel(app.Panel2Tab);
            app.PlantKnob_7 = uiknob(app.PlantKnobPanel_7, 'continuous');
            app.PlantKnob_7.Limits = [0 5];
            app.PlantKnob_7.Value = 2;
            app.PlantKnobLabel_7 = uilabel(app.PlantKnobPanel_7);
            app.PlantKnobLabel_7.Text = 'Knob';
            app.PlantKnobLow_7 = uieditfield(app.PlantKnobPanel_7, 'numeric');
            app.PlantKnobLow_7.ValueChangedFcn = createCallbackFcn(app, @PlantKnobLow_7ValueChanged, true);
            app.PlantKnobHigh_7 = uieditfield(app.PlantKnobPanel_7, 'numeric');
            app.PlantKnobHigh_7.ValueChangedFcn = createCallbackFcn(app, @PlantKnobHigh_7ValueChanged, true);
            app.PlantKnobHigh_7.Value = 5;
            app.PlantKnobPanel_8 = uipanel(app.Panel2Tab);
            app.PlantKnob_8 = uiknob(app.PlantKnobPanel_8, 'continuous');
            app.PlantKnob_8.Limits = [0 10];
            app.PlantKnob_8.Value = 5;
            app.PlantKnobLabel_8 = uilabel(app.PlantKnobPanel_8);
            app.PlantKnobLabel_8.Text = 'Knob';
            app.PlantKnobLow_8 = uieditfield(app.PlantKnobPanel_8, 'numeric');
            app.PlantKnobLow_8.ValueChangedFcn = createCallbackFcn(app, @PlantKnobLow_8ValueChanged, true);
            app.PlantKnobHigh_8 = uieditfield(app.PlantKnobPanel_8, 'numeric');
            app.PlantKnobHigh_8.ValueChangedFcn = createCallbackFcn(app, @PlantKnobHigh_8ValueChanged, true);
            app.PlantKnobHigh_8.Value = 10;
            app.Panel3Tab_2 = uitab(app.TabGroup2);
            app.Panel3Tab_2.Title = 'Panel 3';
            app.PlantKnobPanel_9 = uipanel(app.Panel3Tab_2);
            app.PlantKnob_9 = uiknob(app.PlantKnobPanel_9, 'continuous');
            app.PlantKnob_9.Limits = [0 0.3];
            app.PlantKnob_9.Value = 0.1;
            app.PlantKnobLabel_9 = uilabel(app.PlantKnobPanel_9);
            app.PlantKnobLabel_9.Text = 'Knob';
            app.PlantKnobLow_9 = uieditfield(app.PlantKnobPanel_9, 'numeric');
            app.PlantKnobLow_9.ValueChangedFcn = createCallbackFcn(app, @PlantKnobLow_9ValueChanged, true);
            app.PlantKnobHigh_9 = uieditfield(app.PlantKnobPanel_9, 'numeric');
            app.PlantKnobHigh_9.ValueChangedFcn = createCallbackFcn(app, @PlantKnobHigh_9ValueChanged, true);
            app.PlantKnobHigh_9.Value = 0.3;
            app.PlantKnobPanel_10 = uipanel(app.Panel3Tab_2);
            app.PlantKnob_10 = uiknob(app.PlantKnobPanel_10, 'continuous');
            app.PlantKnob_10.Limits = [0 10];
            app.PlantKnob_10.Value = 5;
            app.PlantKnobLabel_10 = uilabel(app.PlantKnobPanel_10);
            app.PlantKnobLabel_10.Text = 'Knob';
            app.PlantKnobLow_10 = uieditfield(app.PlantKnobPanel_10, 'numeric');
            app.PlantKnobLow_10.ValueChangedFcn = createCallbackFcn(app, @PlantKnobLow_10ValueChanged, true);
            app.PlantKnobHigh_10 = uieditfield(app.PlantKnobPanel_10, 'numeric');
            app.PlantKnobHigh_10.ValueChangedFcn = createCallbackFcn(app, @PlantKnobHigh_10ValueChanged, true);
            app.PlantKnobHigh_10.Value = 10;
            app.PlantKnobPanel_11 = uipanel(app.Panel3Tab_2);
            app.PlantKnob_11 = uiknob(app.PlantKnobPanel_11, 'continuous');
            app.PlantKnob_11.Limits = [0 5];
            app.PlantKnob_11.Value = 2;
            app.PlantKnobLabel_11 = uilabel(app.PlantKnobPanel_11);
            app.PlantKnobLabel_11.Text = 'Knob';
            app.PlantKnobLow_11 = uieditfield(app.PlantKnobPanel_11, 'numeric');
            app.PlantKnobLow_11.ValueChangedFcn = createCallbackFcn(app, @PlantKnobLow_11ValueChanged, true);
            app.PlantKnobHigh_11 = uieditfield(app.PlantKnobPanel_11, 'numeric');
            app.PlantKnobHigh_11.ValueChangedFcn = createCallbackFcn(app, @PlantKnobHigh_11ValueChanged, true);
            app.PlantKnobHigh_11.Value = 5;
            app.PlantKnobPanel_12 = uipanel(app.Panel3Tab_2);
            app.PlantKnob_12 = uiknob(app.PlantKnobPanel_12, 'continuous');
            app.PlantKnob_12.Limits = [0 10];
            app.PlantKnob_12.Value = 5;
            app.PlantKnobLabel_12 = uilabel(app.PlantKnobPanel_12);
            app.PlantKnobLabel_12.Text = 'Knob';
            app.PlantKnobLow_12 = uieditfield(app.PlantKnobPanel_12, 'numeric');
            app.PlantKnobLow_12.ValueChangedFcn = createCallbackFcn(app, @PlantKnobLow_12ValueChanged, true);
            app.PlantKnobHigh_12 = uieditfield(app.PlantKnobPanel_12, 'numeric');
            app.PlantKnobHigh_12.ValueChangedFcn = createCallbackFcn(app, @PlantKnobHigh_12ValueChanged, true);
            app.PlantKnobHigh_12.Value = 10;
            app.Panel4Tab = uitab(app.TabGroup2);
            app.Panel4Tab.Title = 'Panel 4';
            app.PlantKnobPanel_13 = uipanel(app.Panel4Tab);
            app.PlantKnob_13 = uiknob(app.PlantKnobPanel_13, 'continuous');
            app.PlantKnob_13.Limits = [0 0.3];
            app.PlantKnob_13.Value = 0.1;
            app.PlantKnobLabel_13 = uilabel(app.PlantKnobPanel_13);
            app.PlantKnobLabel_13.Text = 'Knob';
            app.PlantKnobLow_13 = uieditfield(app.PlantKnobPanel_13, 'numeric');
            app.PlantKnobLow_13.ValueChangedFcn = createCallbackFcn(app, @PlantKnobLow_13ValueChanged, true);
            app.PlantKnobHigh_13 = uieditfield(app.PlantKnobPanel_13, 'numeric');
            app.PlantKnobHigh_13.ValueChangedFcn = createCallbackFcn(app, @PlantKnobHigh_13ValueChanged, true);
            app.PlantKnobHigh_13.Value = 0.3;
            app.PlantKnobPanel_14 = uipanel(app.Panel4Tab);
            app.PlantKnob_14 = uiknob(app.PlantKnobPanel_14, 'continuous');
            app.PlantKnob_14.Limits = [0 10];
            app.PlantKnob_14.Value = 5;
            app.PlantKnobLabel_14 = uilabel(app.PlantKnobPanel_14);
            app.PlantKnobLabel_14.Text = 'Knob';
            app.PlantKnobLow_14 = uieditfield(app.PlantKnobPanel_14, 'numeric');
            app.PlantKnobLow_14.ValueChangedFcn = createCallbackFcn(app, @PlantKnobLow_14ValueChanged, true);
            app.PlantKnobHigh_14 = uieditfield(app.PlantKnobPanel_14, 'numeric');
            app.PlantKnobHigh_14.ValueChangedFcn = createCallbackFcn(app, @PlantKnobHigh_14ValueChanged, true);
            app.PlantKnobHigh_14.Value = 10;
            app.PlantKnobPanel_15 = uipanel(app.Panel4Tab);
            app.PlantKnob_15 = uiknob(app.PlantKnobPanel_15, 'continuous');
            app.PlantKnob_15.Limits = [0 5];
            app.PlantKnob_15.Value = 2;
            app.PlantKnobLabel_15 = uilabel(app.PlantKnobPanel_15);
            app.PlantKnobLabel_15.Text = 'Knob';
            app.PlantKnobLow_15 = uieditfield(app.PlantKnobPanel_15, 'numeric');
            app.PlantKnobLow_15.ValueChangedFcn = createCallbackFcn(app, @PlantKnobLow_15ValueChanged, true);
            app.PlantKnobHigh_15 = uieditfield(app.PlantKnobPanel_15, 'numeric');
            app.PlantKnobHigh_15.ValueChangedFcn = createCallbackFcn(app, @PlantKnobHigh_15ValueChanged, true);
            app.PlantKnobHigh_15.Value = 5;
            app.PlantKnobPanel_16 = uipanel(app.Panel4Tab);
            app.PlantKnob_16 = uiknob(app.PlantKnobPanel_16, 'continuous');
            app.PlantKnob_16.Limits = [0 10];
            app.PlantKnob_16.Value = 5;
            app.PlantKnobLabel_16 = uilabel(app.PlantKnobPanel_16);
            app.PlantKnobLabel_16.Text = 'Knob';
            app.PlantKnobLow_16 = uieditfield(app.PlantKnobPanel_16, 'numeric');
            app.PlantKnobLow_16.ValueChangedFcn = createCallbackFcn(app, @PlantKnobLow_16ValueChanged, true);
            app.PlantKnobHigh_16 = uieditfield(app.PlantKnobPanel_16, 'numeric');
            app.PlantKnobHigh_16.ValueChangedFcn = createCallbackFcn(app, @PlantKnobHigh_16ValueChanged, true);
            app.PlantKnobHigh_16.Value = 10;
            app.DeterministicSimulationPanel = uipanel(app.UIFigure);
            app.DeterministicSimulationPanel.Title = 'Deterministic Simulation';
            app.DeterministicResponseAxes = uiaxes(app.DeterministicSimulationPanel);
            title(app.DeterministicResponseAxes, 'Response')
            app.DeterministicResponseAxes.YAxisLocation = 'origin';
            app.RunDeterministicButton = uibutton(app.DeterministicSimulationPanel, 'push');
            app.RunDeterministicButton.ButtonPushedFcn = createCallbackFcn(app, @RunDeterministicButtonPushed, true);
            app.RunDeterministicButton.Text = 'Run';
            app.DeterministicSimulationSettingsPanel = uipanel(app.DeterministicSimulationPanel);
            app.DeterministicSimulationSettingsPanel.Title = 'Settings';
            app.TimeSamplesPanelLow = uieditfield(app.DeterministicSimulationSettingsPanel, 'numeric');
            app.TimeSamplesPanelLow.ValueChangedFcn = createCallbackFcn(app, @TimeSamplesPanelLowValueChanged, true);
            app.TimeSamplesPanelLow.Value = 1;
            app.TimeSamplesPanelHigh = uieditfield(app.DeterministicSimulationSettingsPanel, 'numeric');
            app.TimeSamplesPanelHigh.ValueChangedFcn = createCallbackFcn(app, @TimeSamplesPanelHighValueChanged, true);
            app.TimeSamplesPanelHigh.Value = 500;
            app.TimeSamplesSliderLabel = uilabel(app.DeterministicSimulationSettingsPanel);
            app.TimeSamplesSliderLabel.Text = 'Time Samples';
            app.TimeSamplesSlider = uislider(app.DeterministicSimulationSettingsPanel);
            app.TimeSamplesSlider.Limits = [100 500];
            app.TimeSamplesSlider.Value = 500;
            app.SolverDropDownLabel = uilabel(app.DeterministicSimulationSettingsPanel);
            app.SolverDropDownLabel.Text = 'Solver';
            app.SolverDropDown = uidropdown(app.DeterministicSimulationSettingsPanel);
            app.SolverDropDown.Items = {'ODE45', 'ODE23s', 'ODE15s'};
            app.SolverDropDown.Value = 'ODE23s';
            app.SimulationTimeEditFieldLabel = uilabel(app.DeterministicSimulationSettingsPanel);
            app.SimulationTimeEditFieldLabel.Text = 'Simulation Time';
            app.DeterministicSimulationTime = uieditfield(app.DeterministicSimulationSettingsPanel, 'numeric');
            app.DeterministicSimulationTime.Value = 100;
            app.ExportDeterministicButton = uibutton(app.DeterministicSimulationPanel, 'push');
            app.ExportDeterministicButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDeterministicButtonPushed, true);
            app.ExportDeterministicButton.Text = {'Export'; ''};
            app.DeterministicPlots = uibutton(app.DeterministicSimulationPanel, 'push');
            app.DeterministicPlots.ButtonPushedFcn = createCallbackFcn(app, @DeterministicPlotsButtonPushed, true);
            app.DeterministicPlots.Text = 'Detailed Plots';
            app.StochasticSimulationPanel = uipanel(app.UIFigure);
            app.StochasticSimulationPanel.Title = 'Stochastic Simulation';
            app.StochasticResponseAxes = uiaxes(app.StochasticSimulationPanel);
            title(app.StochasticResponseAxes, 'Response')
            app.StochasticResponseAxes.YAxisLocation = 'origin';
            app.RunStochasticButton = uibutton(app.StochasticSimulationPanel, 'push');
            app.RunStochasticButton.ButtonPushedFcn = createCallbackFcn(app, @RunStochasticButtonPushed, true);
            app.RunStochasticButton.Text = 'Run';
            app.StochasticSimulationSettingsPanel = uipanel(app.StochasticSimulationPanel);
            app.StochasticSimulationSettingsPanel.Title = 'Settings';
            app.StochasticTimeSamplesSliderLow = uieditfield(app.StochasticSimulationSettingsPanel, 'numeric');
            app.StochasticTimeSamplesSliderLow.ValueChangedFcn = createCallbackFcn(app, @StochasticTimeSamplesSliderLowValueChanged, true);
            app.StochasticTimeSamplesSliderLow.Value = 1;
            app.StochasticTimeSamplesSliderHigh = uieditfield(app.StochasticSimulationSettingsPanel, 'numeric');
            app.StochasticTimeSamplesSliderHigh.ValueChangedFcn = createCallbackFcn(app, @StochasticTimeSamplesSliderHighValueChanged, true);
            app.StochasticTimeSamplesSliderHigh.Value = 100;
            app.TimeSamplesSlider_2Label = uilabel(app.StochasticSimulationSettingsPanel);
            app.TimeSamplesSlider_2Label.Text = 'Time Samples';
            app.StochasticTimeSamplesSlider = uislider(app.StochasticSimulationSettingsPanel);
            app.StochasticTimeSamplesSlider.Limits = [100 500];
            app.StochasticTimeSamplesSlider.Value = 100;
            app.TrajectoriesLow = uieditfield(app.StochasticSimulationSettingsPanel, 'numeric');
            app.TrajectoriesLow.ValueChangedFcn = createCallbackFcn(app, @TrajectoriesLowValueChanged, true);
            app.TrajectoriesLow.Value = 1;
            app.TrajectoriesHigh = uieditfield(app.StochasticSimulationSettingsPanel, 'numeric');
            app.TrajectoriesHigh.ValueChangedFcn = createCallbackFcn(app, @TrajectoriesHighValueChanged, true);
            app.TrajectoriesHigh.Value = 10;
            app.NumberofTrajectoriesSliderLabel = uilabel(app.StochasticSimulationSettingsPanel);
            app.NumberofTrajectoriesSliderLabel.Text = 'Number of Trajectories';
            app.NumberofTrajectoriesSlider = uislider(app.StochasticSimulationSettingsPanel);
            app.NumberofTrajectoriesSlider.Limits = [1 10];
            app.NumberofTrajectoriesSlider.Value = 1;
            app.SimulationTimeEditFieldLabel_2 = uilabel(app.StochasticSimulationSettingsPanel);
            app.SimulationTimeEditFieldLabel_2.Text = 'Simulation Time';
            app.StochasticSimulationTime = uieditfield(app.StochasticSimulationSettingsPanel, 'numeric');
            app.StochasticSimulationTime.Value = 100;
            app.MaximumNumberofEventsLabel = uilabel(app.StochasticSimulationSettingsPanel);
            app.MaximumNumberofEventsLabel.Text = {'Maximum Number '; 'of Events'};
            app.MaximumNumberofEvents = uieditfield(app.StochasticSimulationSettingsPanel, 'numeric');
            app.MaximumNumberofEvents.Value = 10000;
            app.ExportStochasticButton = uibutton(app.StochasticSimulationPanel, 'push');
            app.ExportStochasticButton.ButtonPushedFcn = createCallbackFcn(app, @ExportStochasticButtonPushed, true);
            app.ExportStochasticButton.Text = {'Export'; ''};
            app.StochasticPlots = uibutton(app.StochasticSimulationPanel, 'push');
            app.StochasticPlots.ButtonPushedFcn = createCallbackFcn(app, @StochasticPlotsButtonPushed, true);
            app.StochasticPlots.Text = 'Detailed Plots';
            app.withStatisticsCheckBox = uicheckbox(app.StochasticSimulationPanel);
            app.withStatisticsCheckBox.Text = 'with Statistics';
            app.Statistics = uibutton(app.StochasticSimulationPanel, 'push');
            app.Statistics.ButtonPushedFcn = createCallbackFcn(app, @StatisticsButtonPushed, true);
            app.Statistics.Text = 'Statistics';
            app.ParallelComputingToolboxCheckBox = uicheckbox(app.StochasticSimulationPanel);
            app.ParallelComputingToolboxCheckBox.Text = {'Parallel '; 'Computing '; 'Toolbox'};
            app.TuningCurvesPanel = uipanel(app.UIFigure);
            app.TuningCurvesPanel.Title = 'Tuning Curves';
            app.TuningCurvesAxes = uiaxes(app.TuningCurvesPanel);
            app.TuningCurvesAxes.YAxisLocation = 'origin';
            app.TuningCurvesAxes.YTick = [0 0.5 1];
            app.SetPointEditFieldLabel = uilabel(app.TuningCurvesPanel);
            app.SetPointEditFieldLabel.Text = 'SetPoint';
            app.SetPointEditField = uieditfield(app.TuningCurvesPanel, 'text');
            app.DisturbanceTrackingPanel = uipanel(app.UIFigure);
            app.DisturbanceTrackingPanel.Title = 'Disturbance & Tracking Panel';
            app.SwitchDisturbance = uiswitch(app.DisturbanceTrackingPanel, 'toggle');
            app.SwitchDisturbance.ValueChangedFcn = createCallbackFcn(app, @SwitchDisturbanceValueChanged, true);
            app.t1EditFieldLabel = uilabel(app.DisturbanceTrackingPanel);
            app.t1EditFieldLabel.Text = 't1';
            app.t1Disturbance = uieditfield(app.DisturbanceTrackingPanel, 'numeric');
            app.t1Disturbance.Enable = 'off';
            app.t2EditFieldLabel = uilabel(app.DisturbanceTrackingPanel);
            app.t2EditFieldLabel.Text = 't2';
            app.t2Disturbance = uieditfield(app.DisturbanceTrackingPanel, 'numeric');
            app.t2Disturbance.Enable = 'off';
            app.xLabel = uilabel(app.DisturbanceTrackingPanel);
            app.xLabel.Text = 'x';
            app.t1DisturbanceFactor_Param_1 = uieditfield(app.DisturbanceTrackingPanel, 'numeric');
            app.t1DisturbanceFactor_Param_1.Enable = 'off';
            app.t1DisturbanceFactor_Param_1.Value = 1;
            app.xLabel_2 = uilabel(app.DisturbanceTrackingPanel);
            app.xLabel_2.Text = 'x';
            app.t1DisturbanceFactor_Param_2 = uieditfield(app.DisturbanceTrackingPanel, 'numeric');
            app.t1DisturbanceFactor_Param_2.Enable = 'off';
            app.t1DisturbanceFactor_Param_2.Value = 1;
            app.xLabel_3 = uilabel(app.DisturbanceTrackingPanel);
            app.xLabel_3.Text = 'x';
            app.t1DisturbanceFactor_Param_3 = uieditfield(app.DisturbanceTrackingPanel, 'numeric');
            app.t1DisturbanceFactor_Param_3.Enable = 'off';
            app.t1DisturbanceFactor_Param_3.Value = 1;
            app.xLabel_4 = uilabel(app.DisturbanceTrackingPanel);
            app.xLabel_4.Text = 'x';
            app.t2DisturbanceFactor_Param_1 = uieditfield(app.DisturbanceTrackingPanel, 'numeric');
            app.t2DisturbanceFactor_Param_1.Enable = 'off';
            app.t2DisturbanceFactor_Param_1.Value = 1;
            app.xLabel_5 = uilabel(app.DisturbanceTrackingPanel);
            app.xLabel_5.Text = 'x';
            app.t2DisturbanceFactor_Param_2 = uieditfield(app.DisturbanceTrackingPanel, 'numeric');
            app.t2DisturbanceFactor_Param_2.Enable = 'off';
            app.t2DisturbanceFactor_Param_2.Value = 1;
            app.xLabel_6 = uilabel(app.DisturbanceTrackingPanel);
            app.xLabel_6.Text = 'x';
            app.t2DisturbanceFactor_Param_3 = uieditfield(app.DisturbanceTrackingPanel, 'numeric');
            app.t2DisturbanceFactor_Param_3.Enable = 'off';
            app.t2DisturbanceFactor_Param_3.Value = 1;
            app.Lamp = uilamp(app.DisturbanceTrackingPanel);
            app.CheckBox_1 = uicheckbox(app.DisturbanceTrackingPanel);
            app.CheckBox_1.ValueChangedFcn = createCallbackFcn(app, @CheckBox_1ValueChanged, true);
            app.CheckBox_1.Enable = 'off';
            app.CheckBox_1.Text = '';
            app.CheckBox_2 = uicheckbox(app.DisturbanceTrackingPanel);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Enable = 'off';
            app.CheckBox_2.Text = '';
            app.DropDown_Param_1 = uidropdown(app.DisturbanceTrackingPanel);
            app.DropDown_Param_1.Items = {};
            app.DropDown_Param_1.Value = {};
            app.DropDown_Param_2 = uidropdown(app.DisturbanceTrackingPanel);
            app.DropDown_Param_2.Items = {};
            app.DropDown_Param_2.Value = {};
            app.DropDown_Param_3 = uidropdown(app.DisturbanceTrackingPanel);
            app.DropDown_Param_3.Items = {};
            app.DropDown_Param_3.Value = {};
            app.LastComputationTimeLabel = uilabel(app.UIFigure);
            app.LastComputationTimeLabel.Text = 'Last Computation Time:';
            app.ComputationTime = uilabel(app.UIFigure);
            app.ComputationTime.Text = '';
            app.StatusProcessingLabel = uilabel(app.UIFigure);
            app.StatusProcessingLabel.Text = 'Status: Processing ...';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NetworkDescriptionTab = uitab(app.TabGroup);
            app.NetworkDescriptionTab.Title = 'Network Description';
            app.PlantPanel_2 = uipanel(app.NetworkDescriptionTab);
            app.PlantPanel_2.Title = 'Plant';
            app.PlantReactionsTable = uitable(app.PlantPanel_2);
            app.PlantReactionsTable.ColumnName = {'Reactants'; 'Products'; 'Propensities'};
            app.PlantReactionsTable.RowName = {};
            app.ListofPlantSpeciesLabel = uilabel(app.PlantPanel_2);
            app.ListofPlantSpeciesLabel.Text = {'List of '; 'Plant '; 'Species'};
            app.NamesListPlant = uilistbox(app.PlantPanel_2);
            app.NamesListPlant.Items = {};
            app.NamesListPlant.Value = {};
            app.OutputSpeciesDropDownLabel = uilabel(app.PlantPanel_2);
            app.OutputSpeciesDropDownLabel.Enable = 'off';
            app.OutputSpeciesDropDownLabel.Text = 'Output Species';
            app.OutputSpeciesDropDown = uidropdown(app.PlantPanel_2);
            app.OutputSpeciesDropDown.Items = {};
            app.OutputSpeciesDropDown.Enable = 'off';
            app.OutputSpeciesDropDown.Value = {};
            app.ControllerPanel = uipanel(app.NetworkDescriptionTab);
            app.ControllerPanel.Title = 'Controller';
            app.ControllerReactionsTable = uitable(app.ControllerPanel);
            app.ControllerReactionsTable.ColumnName = {'Reactants'; 'Products'; 'Propensities'};
            app.ControllerReactionsTable.RowName = {};
            app.ListofControllerSpeciesLabel = uilabel(app.ControllerPanel);
            app.ListofControllerSpeciesLabel.Text = {'List of '; 'Controller '; 'Species'};
            app.NamesListController = uilistbox(app.ControllerPanel);
            app.NamesListController.Items = {};
            app.NamesListController.Value = {};
            app.NetworkDiagramTab = uitab(app.TabGroup);
            app.NetworkDiagramTab.Title = 'Network Diagram';
            app.Image = uiimage(app.NetworkDiagramTab);
            app.MauriceFiloDBSSEETHzLabel = uilabel(app.UIFigure);
            app.MauriceFiloDBSSEETHzLabel.Text = 'Maurice Filo, DBSSE, ETHz';
            app.SymbolicDisplayButton = uibutton(app.UIFigure, 'push');
            app.SymbolicDisplayButton.ButtonPushedFcn = createCallbackFcn(app, @SymbolicDisplayButtonPushed, true);
            app.SymbolicDisplayButton.Text = 'Symbolic Display';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BMC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end