classdef AddNumbers < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        SaveCloseButton         matlab.ui.control.Button
        ParametersLabel         matlab.ui.control.Label
        InitialConditionsLabel  matlab.ui.control.Label
        ParametersTable         matlab.ui.control.Table
        ICTable                 matlab.ui.control.Table
    end

    
    properties (Access = private)
        N
        M
        InitialConditions
        ParameterValues
    end
    
    properties (Access = public)
        MotherApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MotherApp)
            app.MotherApp = MotherApp;
            Species = [MotherApp.PlantSpeciesNames, MotherApp.ControllerSpeciesNames];
            Parameters = [MotherApp.PlantParameters, MotherApp.ControllerParameters];
            app.N = length(Species);
            app.M = length(Parameters);
            app.InitialConditions = zeros(app.N, 1);
            app.ParameterValues = ones(app.M, 1);
            for i = 1 : app.N
                app.ICTable.Data{i,1} = Species{i};
                app.ICTable.Data{i,2} = app.InitialConditions(i);
            end
            app.ICTable.ColumnEditable = [false, true];
            for i = 1 : app.M
                app.ParametersTable.Data{i,1} = Parameters{i};
                app.ParametersTable.Data{i,2} = app.ParameterValues(i);
            end
            app.ParametersTable.ColumnEditable = [false, true];
        end

        % Button pushed function: SaveCloseButton
        function SaveCloseButtonPushed(app, event)
            for i = 1 : app.N
                app.InitialConditions(i) = app.ICTable.Data{i,2};
            end
            for i = 1 : app.M
                app.ParameterValues(i) = app.ParametersTable.Data{i,2};
            end
            app.MotherApp.InitialConditions = app.InitialConditions;
            app.MotherApp.ParameterValues = app.ParameterValues;
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ICTable = uitable(app.UIFigure);
            app.ICTable.ColumnName = {'Species'; 'Initial Conditions'};
            app.ICTable.RowName = {};
            app.ParametersTable = uitable(app.UIFigure);
            app.ParametersTable.ColumnName = {'Parameters'; 'Values'};
            app.ParametersTable.RowName = {};
            app.InitialConditionsLabel = uilabel(app.UIFigure);
            app.InitialConditionsLabel.Text = 'Initial Conditions';
            app.ParametersLabel = uilabel(app.UIFigure);
            app.ParametersLabel.Text = 'Parameters';
            app.SaveCloseButton = uibutton(app.UIFigure, 'push');
            app.SaveCloseButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCloseButtonPushed, true);
            app.SaveCloseButton.Text = 'Save & Close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AddNumbers(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end