classdef SymbolicDisplay < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        CloseButton               matlab.ui.control.Button
        ExportButton              matlab.ui.control.Button
        ODEsLabel                 matlab.ui.control.Label
        PropensityFunctionLabel   matlab.ui.control.Label
        StoichiometryMatrixLabel  matlab.ui.control.Label
        Prop                      matlab.ui.control.Label
        S                         matlab.ui.control.Label
        Equations                 matlab.ui.control.Label
    end

    
    properties (Access = private)
        Parameters 
        Variables
        StoichiometryMatrix
        PropensityFunction
        NetworkName
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Parameters, Variables, StoichiometryMatrix, PropensityFunction, NetworkName)
            % App Properties
            app.Parameters = Parameters;
            app.Variables = Variables;
            app.StoichiometryMatrix = StoichiometryMatrix;
            app.PropensityFunction = PropensityFunction;
            app.NetworkName = NetworkName;
            % ODEs
            app.Equations.Interpreter = 'latex';
            RHS = StoichiometryMatrix * PropensityFunction(Variables, Parameters);
            RHS_Latex = cell(length(RHS),1);
            for i = 1 : length(RHS)
                RHS_Latex{i} = ['$ \dot ', Variables{i}, ' = ', latex(RHS(i)), '$'];
            end
            app.Equations.Text = RHS_Latex;
            % Stoichiometry Matrix
            app.S.Interpreter = 'latex';
            app.S.Text = ['$', latex(sym(StoichiometryMatrix)), '$'];
            % Propensity Function
            app.Prop.Interpreter = 'latex';
            app.Prop.Text = ['$', latex(PropensityFunction(Variables, Parameters)), '$'];
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            RHS = app.StoichiometryMatrix * app.PropensityFunction(app.Variables, app.Parameters);
            Params = fieldnames(app.Parameters);
            for i = 1 : length(Params)
               eval([Params{i}, ' = sym(''', Params{i}, ''', ''positive'');']); 
            end
            Vars = app.Variables;
            for i = 1 : length(Vars)
               eval([Vars{i}, ' = sym(''', Vars{i}, ''', ''positive'');']); 
            end
            FilePath = uigetdir('', 'Select Folder');
            save([FilePath, '/Symbolic_', app.NetworkName], '-regexp', '^(?!(app|i|Params|Vars|FilePath|event)$).');
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Equations = uilabel(app.UIFigure);
            app.S = uilabel(app.UIFigure);
            app.S.Text = 'Label2';
            app.Prop = uilabel(app.UIFigure);
            app.Prop.Text = 'Label3';
            app.StoichiometryMatrixLabel = uilabel(app.UIFigure);
            app.StoichiometryMatrixLabel.Text = 'Stoichiometry Matrix:';
            app.PropensityFunctionLabel = uilabel(app.UIFigure);
            app.PropensityFunctionLabel.Text = 'Propensity Function:';
            app.ODEsLabel = uilabel(app.UIFigure);
            app.ODEsLabel.Text = 'ODEs:';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SymbolicDisplay(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end